classdef F1Optimizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        F1OptimizerUIFigure             matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        SelectorCircuitoTab             matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        TemporadaSwitch                 matlab.ui.control.Switch
        SeleccionarTodosCheckBox        matlab.ui.control.CheckBox
        PanelAustralia                  matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        SeleccionarCheckBoxAustralia    matlab.ui.control.CheckBox
        ButtonAustralia                 matlab.ui.control.Button
        australiaflag                   matlab.ui.control.Image
        australia                       matlab.ui.control.Image
        nombreLabel                     matlab.ui.control.Label
        PanelAustria                    matlab.ui.container.Panel
        GridLayout3_3                   matlab.ui.container.GridLayout
        SeleccionarCheckBoxAustria      matlab.ui.control.CheckBox
        ButtonAustria                   matlab.ui.control.Button
        austriaflag_3                   matlab.ui.control.Image
        austria_3                       matlab.ui.control.Image
        nombreLabel_3                   matlab.ui.control.Label
        PanelBarcelona                  matlab.ui.container.Panel
        GridLayout3_4                   matlab.ui.container.GridLayout
        ButtonBarcelona                 matlab.ui.control.Button
        SeleccionarCheckBoxBarcelona    matlab.ui.control.CheckBox
        barcelonaflag_4                 matlab.ui.control.Image
        barcelona_4                     matlab.ui.control.Image
        nombreLabel_4                   matlab.ui.control.Label
        PanelBarein                     matlab.ui.container.Panel
        GridLayout3_5                   matlab.ui.container.GridLayout
        ButtonBarein                    matlab.ui.control.Button
        SeleccionarCheckBoxBarein       matlab.ui.control.CheckBox
        Bareinflag_5                    matlab.ui.control.Image
        Barein_5                        matlab.ui.control.Image
        nombreLabel_5                   matlab.ui.control.Label
        PanelCanada                     matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        ButtonCanada                    matlab.ui.control.Button
        SeleccionarCheckBoxCanada       matlab.ui.control.CheckBox
        Canadaflag_2                    matlab.ui.control.Image
        Canada_2                        matlab.ui.control.Image
        nombreLabel_2                   matlab.ui.control.Label
        PanelInterlagos                 matlab.ui.container.Panel
        GridLayout3_6                   matlab.ui.container.GridLayout
        ButtonInterlagos                matlab.ui.control.Button
        SeleccionarCheckBoxInterlagos   matlab.ui.control.CheckBox
        Interlagosflag_6                matlab.ui.control.Image
        Interlagos_6                    matlab.ui.control.Image
        nombreLabel_6                   matlab.ui.control.Label
        PanelMonaco                     matlab.ui.container.Panel
        GridLayout3_7                   matlab.ui.container.GridLayout
        ButtonMonaco                    matlab.ui.control.Button
        SeleccionarCheckBoxMonaco       matlab.ui.control.CheckBox
        Monacoflag_7                    matlab.ui.control.Image
        Monaco_7                        matlab.ui.control.Image
        nombreLabel_7                   matlab.ui.control.Label
        PanelMonza                      matlab.ui.container.Panel
        GridLayout3_8                   matlab.ui.container.GridLayout
        ButtonMonza                     matlab.ui.control.Button
        SeleccionarCheckBoxMonza        matlab.ui.control.CheckBox
        Monzaflag_8                     matlab.ui.control.Image
        Monza_8                         matlab.ui.control.Image
        nombreLabel_8                   matlab.ui.control.Label
        PanelSilverstone                matlab.ui.container.Panel
        GridLayout3_9                   matlab.ui.container.GridLayout
        ButtonSilverstone               matlab.ui.control.Button
        SeleccionarCheckBoxSilverstone  matlab.ui.control.CheckBox
        Silverstoneflag_9               matlab.ui.control.Image
        Silverstone_9                   matlab.ui.control.Image
        nombreLabel_9                   matlab.ui.control.Label
        PanelSingapur                   matlab.ui.container.Panel
        GridLayout3_10                  matlab.ui.container.GridLayout
        ButtonSingapur                  matlab.ui.control.Button
        SeleccionarCheckBoxSingapur     matlab.ui.control.CheckBox
        Singapurflag_10                 matlab.ui.control.Image
        Singapur_10                     matlab.ui.control.Image
        nombreLabel_10                  matlab.ui.control.Label
        PanelSpa                        matlab.ui.container.Panel
        GridLayout3_11                  matlab.ui.container.GridLayout
        ButtonSpa                       matlab.ui.control.Button
        SeleccionarCheckBoxSpa          matlab.ui.control.CheckBox
        Spaflag_11                      matlab.ui.control.Image
        Spa_11                          matlab.ui.control.Image
        nombreLabel_11                  matlab.ui.control.Label
        PanelSuzuka                     matlab.ui.container.Panel
        GridLayout3_12                  matlab.ui.container.GridLayout
        ButtonSuzuka                    matlab.ui.control.Button
        SeleccionarCheckBoxSuzuka       matlab.ui.control.CheckBox
        Suzukaflag_12                   matlab.ui.control.Image
        Suzuka_12                       matlab.ui.control.Image
        nombreLabel_12                  matlab.ui.control.Label
        ProcesarButton                  matlab.ui.control.Button
        SelectordeCircuitosLabel        matlab.ui.control.Label
        XButton                         matlab.ui.control.Button
        Panel_info                      matlab.ui.container.Panel
        GridLayout13_2                  matlab.ui.container.GridLayout
        datoLabel_2                     matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        GridLayout14_2                  matlab.ui.container.GridLayout
        DownforceLabel_2                matlab.ui.control.Label
        GLateralLabel_2                 matlab.ui.control.Label
        FrenadasLabel_2                 matlab.ui.control.Label
        TraccinLabel_2                  matlab.ui.control.Label
        numerosLabel_2                  matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        nombrecircuitoLabel_2           matlab.ui.control.Label
        CircuitosProcesadosTab          matlab.ui.container.Tab
        GridLayout2_CircuitosProcesados  matlab.ui.container.GridLayout
        VolverButton                    matlab.ui.control.Button
        PanelAustralia_2                matlab.ui.container.Panel
        GridLayout3_24                  matlab.ui.container.GridLayout
        ButtonAustralia_2               matlab.ui.control.Button
        australiaflag_2                 matlab.ui.control.Image
        australia_2                     matlab.ui.control.Image
        vueltasLabel_24                 matlab.ui.control.Label
        PanelAustria_2                  matlab.ui.container.Panel
        GridLayout3_23                  matlab.ui.container.GridLayout
        ButtonAustria_2                 matlab.ui.control.Button
        austriaflag_4                   matlab.ui.control.Image
        austria_4                       matlab.ui.control.Image
        vueltasLabel_23                 matlab.ui.control.Label
        PanelBarcelona_2                matlab.ui.container.Panel
        GridLayout3_22                  matlab.ui.container.GridLayout
        ButtonBarcelona_2               matlab.ui.control.Button
        barcelonaflag_5                 matlab.ui.control.Image
        barcelona_5                     matlab.ui.control.Image
        vueltasLabel_22                 matlab.ui.control.Label
        PanelBarein_2                   matlab.ui.container.Panel
        GridLayout3_21                  matlab.ui.container.GridLayout
        ButtonBarein_2                  matlab.ui.control.Button
        Bareinflag_6                    matlab.ui.control.Image
        Barein_6                        matlab.ui.control.Image
        vueltasLabel_21                 matlab.ui.control.Label
        PanelCanada_2                   matlab.ui.container.Panel
        GridLayout3_20                  matlab.ui.container.GridLayout
        ButtonCanada_2                  matlab.ui.control.Button
        Canadaflag_3                    matlab.ui.control.Image
        Canada_3                        matlab.ui.control.Image
        vueltasLabel_20                 matlab.ui.control.Label
        PanelInterlagos_2               matlab.ui.container.Panel
        GridLayout3_19                  matlab.ui.container.GridLayout
        ButtonInterlagos_2              matlab.ui.control.Button
        Interlagosflag_7                matlab.ui.control.Image
        Interlagos_7                    matlab.ui.control.Image
        vueltasLabel_19                 matlab.ui.control.Label
        PanelMonaco_2                   matlab.ui.container.Panel
        GridLayout3_18                  matlab.ui.container.GridLayout
        ButtonMonaco_2                  matlab.ui.control.Button
        Monacoflag_8                    matlab.ui.control.Image
        Monaco_8                        matlab.ui.control.Image
        vueltasLabel_18                 matlab.ui.control.Label
        PanelMonza_2                    matlab.ui.container.Panel
        GridLayout3_17                  matlab.ui.container.GridLayout
        ButtonMonza_2                   matlab.ui.control.Button
        Monzaflag_9                     matlab.ui.control.Image
        Monza_9                         matlab.ui.control.Image
        vueltasLabel_17                 matlab.ui.control.Label
        PanelSilverstone_2              matlab.ui.container.Panel
        GridLayout3_16                  matlab.ui.container.GridLayout
        ButtonSilverstone_2             matlab.ui.control.Button
        Silverstoneflag_10              matlab.ui.control.Image
        Silverstone_10                  matlab.ui.control.Image
        vueltasLabel_16                 matlab.ui.control.Label
        PanelSingapur_2                 matlab.ui.container.Panel
        GridLayout3_15                  matlab.ui.container.GridLayout
        ButtonSingapur_2                matlab.ui.control.Button
        Singapurflag_11                 matlab.ui.control.Image
        Singapur_11                     matlab.ui.control.Image
        vueltasLabel_15                 matlab.ui.control.Label
        PanelSpa_2                      matlab.ui.container.Panel
        GridLayout3_14                  matlab.ui.container.GridLayout
        ButtonSpa_2                     matlab.ui.control.Button
        Spaflag_12                      matlab.ui.control.Image
        Spa_12                          matlab.ui.control.Image
        vueltasLabel_14                 matlab.ui.control.Label
        PanelSuzuka_2                   matlab.ui.container.Panel
        GridLayout3_13                  matlab.ui.container.GridLayout
        ButtonSuzuka_2                  matlab.ui.control.Button
        Suzukaflag_13                   matlab.ui.control.Image
        Suzuka_13                       matlab.ui.control.Image
        vueltasLabel_13                 matlab.ui.control.Label
        EntrenarModeloButton            matlab.ui.control.Button
        CircuitosProcesadosLabel        matlab.ui.control.Label
        XButton_2                       matlab.ui.control.Button
        Panel_info_2                    matlab.ui.container.Panel
        GridLayout13_3                  matlab.ui.container.GridLayout
        Panel_6                         matlab.ui.container.Panel
        GridLayout26                    matlab.ui.container.GridLayout
        MejorVueltaLabel                matlab.ui.control.Label
        MetricasLabel                   matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        TelemetriaLabel                 matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        nombrecircuitoLabel_3           matlab.ui.control.Label
        OptimizadorTab                  matlab.ui.container.Tab
        GridLayoutOptimizador           matlab.ui.container.GridLayout
        ExportarButton                  matlab.ui.control.Button
        PanelDerecho                    matlab.ui.container.Panel
        GridLayoutDerecho               matlab.ui.container.GridLayout
        PanelCentral                    matlab.ui.container.Panel
        PanelOptimizador                matlab.ui.container.Panel
        GridSelectorOptimizador         matlab.ui.container.GridLayout
        OptimizadorLabel                matlab.ui.control.Label
        SalirButton                     matlab.ui.control.Button
        VolverButton_2                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        circuitosSeleccionados = {}   % Lista de circuitos marcados
    end

%% Función para procesar el circuito
methods (Access = private)
function mostrarResultadosCircuito(app, circuitoID, nombreprocesado)
            
        pathToApp = fileparts(mfilename('fullpath'));

                % Colores circuitos
                switch circuitoID
                case 'australia', tema=[0,0.1,0.6]; mapa3D=@turbo;
                case 'austria', tema=[0.6,0,0]; mapa3D=@hot;
                case 'barcelona', tema=[1,0.8,0]; mapa3D=@spring;
                case 'barein', tema=[0.6,0.4,0.2]; mapa3D=@copper;
                case 'canada', tema=[0.4,0.8,1]; mapa3D=@winter;
                case 'interlagos', tema=[0,0.5,0]; mapa3D=@parula;
                case 'monaco', tema=[0.85,0.65,0.1]; mapa3D=@pink;
                case 'monza', tema=[0,0.4,0]; mapa3D=@summer;
                case 'silverstone', tema=[0.6,0.6,0.6]; mapa3D=@gray;
                case 'singapur', tema=[0.7,0.4,0.9]; mapa3D=@cool;
                case 'spa', tema=[1,0.5,0]; mapa3D=@autumn;
                case 'suzuka', tema=[0.9,0.9,0.9]; mapa3D=@bone;
                otherwise, tema=[0,0.8,1]; mapa3D=@parula;
                end

        if strcmp(app.TemporadaSwitch.Value, '2026')
            folderDatos = 'datos_2026';
        else
            folderDatos = 'datos';
        end
        % Cargar datos
        try
            lapsData = load(fullfile(pathToApp, folderDatos, sprintf('laps_%s.mat', circuitoID)));
            telData = load(fullfile(pathToApp, folderDatos, sprintf('telemetry_%s.mat', circuitoID)));
            laps = lapsData.laps.lap_data;  
            tel = telData.telemetry;
        catch
            uialert(app.F1OptimizerUIFigure, 'No hay datos procesados.', 'Error');
            return;
        end

        % Textos
        app.nombrecircuitoLabel_3.Text = nombreprocesado;
        % Tiempos por setup
        tiemposText = {'', 'Tiempos de Vuelta por Setup', ''};
        setups = unique(laps(:, 4:5), 'rows');
        setups = sortrows(setups, [2 1]);
        for s = 1:size(setups, 1)
            fw = setups(s, 1); rw = setups(s, 2);
            mask = laps(:,4) == fw & laps(:,5) == rw;
            tiempos = laps(mask, 2);
            tiemposText{end+1} = sprintf('─── FW=%dº, RW=%dº ───', fw, rw);
            for v = 1:length(tiempos)
                tiemposText{end+1} = sprintf('Vuelta %d: %.3fs', v, tiempos(v));
            end
            tiemposText{end+1} = '';  
        end
        app.TextArea.Value = tiemposText;
        % Telemetría
        app.TelemetriaLabel.Text = {
            'Telemetría (mín/media/máx):';
            sprintf('- Velocidad: (%.0f / %.0f / %.0f) km/h', min(tel.speed), mean(tel.speed), max(tel.speed));
            sprintf('- G Lateral: (%.2f / %.2f / %.2f) g', min(tel.glat), mean(tel.glat), max(tel.glat));
            sprintf('- G Longitudinal: (%.2f / %.2f / %.2f) g', min(tel.glong), mean(tel.glong), max(tel.glong));
            sprintf('- Acelerador: (%.0f / %.0f / %.0f) %%', min(tel.acelerador), mean(tel.acelerador), max(tel.acelerador));
            sprintf('- Freno: (%.0f / %.0f / %.0f) %%', min(tel.freno), mean(tel.freno), max(tel.freno));
            sprintf('- Grados de Giro: (%.0f / %.0f / %.0f) º', min(tel.steering), mean(tel.steering), max(tel.steering));
        };
        % Métricas
        met = tel.lap_metrics;
        app.MetricasLabel.Text = {
            'Métricas [mín-máx]:';
            sprintf('- Velocidad Máxima: [%.1f - %.1f] km/h', min(met.v_max), max(met.v_max));
            sprintf('- G Lateral Media en Curva: [%.2f - %.2f] g', min(met.glat_media), max(met.glat_media));
            sprintf('- G Long. Media en Aceleración: [%.2f - %.2f] g', min(met.glong_acel), max(met.glong_acel));
            sprintf('- G Long. Media en Frenada: [%.2f - %.2f] g', min(met.glong_freno), max(met.glong_freno));
            sprintf('- %% de la Vuelta Acelerando: [%.1f - %.1f] %%', min(met.t_acelerador)*100, max(met.t_acelerador)*100);
            sprintf('- %% de la Vuelta Frenando: [%.1f - %.1f] %%', min(met.t_freno)*100, max(met.t_freno)*100);
            sprintf('- Variabilidad de la Dirección: [%.1f - %.1f] º', min(met.steering_std), max(met.steering_std));
        };
        % Mejor Vuelta
        [mejorTiempo, idx] = min(laps(:,2));
        app.MejorVueltaLabel.Text = {
            sprintf('Mejor Vuelta: %.3f s', mejorTiempo);
            sprintf('(FW=%dº, RW=%dº)', laps(idx, 4), laps(idx, 5))
        };
        % Gráfica 3D
        cla(app.UIAxes2); hold(app.UIAxes2, 'on');
        zBase = min(laps(:,2)) - 0.5;
        for i = 1:size(laps, 1)
            plot3(app.UIAxes2, [laps(i,4) laps(i,4)], [laps(i,5) laps(i,5)], [zBase laps(i,2)], 'Color', [0.5 0.5 0.5], 'LineWidth', 0.5);
        end
        scatter3(app.UIAxes2, laps(:,4), laps(:,5), laps(:,2), 60, laps(:,2), 'filled');
        hold(app.UIAxes2, 'off');
        app.UIAxes2.Color = [0.15 0.15 0.15];
        app.UIAxes2.XColor = 'w'; app.UIAxes2.YColor = 'w'; app.UIAxes2.ZColor = 'w';
        title(app.UIAxes2, 'Setup vs Tiempo', 'Color', 'w');
        xlabel(app.UIAxes2, 'FW (º)'); ylabel(app.UIAxes2, 'RW (º)'); zlabel(app.UIAxes2, 'Tiempo (s)');
        colormap(app.UIAxes2, flipud(mapa3D()));
        view(app.UIAxes2, -20, 10);
        grid(app.UIAxes2, 'on');
        app.UIAxes2.GridColor = [0.4 0.4 0.4];
        % Gráfica Barras
        cla(app.UIAxes);
        setups = unique(laps(:, 4:5), 'rows');
        setups = sortrows(setups, [2 1]);
        n_setups = size(setups, 1); 
        medias = zeros(size(setups, 1), 1);
        etiquetas = {};
        for s = 1:size(setups, 1)
            mask = laps(:,4) == setups(s,1) & laps(:,5) == setups(s,2);
            medias(s) = mean(laps(mask, 2));
            etiquetas{s} = sprintf('FW: %dº, RW: %dº', setups(s,1), setups(s,2));
        end
        % Dibujar barras
        bar(app.UIAxes, medias, 'FaceColor', tema);
        if n_setups > 15
            salto = 2; 
        else
            salto = 1; 
        end
        indices = 1:salto:n_setups;
        app.UIAxes.XLim = [0, n_setups + 1]; 
        app.UIAxes.XTick = indices;        
        app.UIAxes.XTickLabel = etiquetas(indices); 
        app.UIAxes.Color = [0.15 0.15 0.15];
        app.UIAxes.XColor = 'w'; app.UIAxes.YColor = 'w';
        title(app.UIAxes, 'Tiempo medio por setup (s)', 'Color', 'w');
        xlabel(app.UIAxes, ''); ylabel(app.UIAxes, '');
        app.UIAxes.YLim = [min(medias)-0.5, max(medias)+0.5];
        % Rescalado
        app.Panel_info_2.Parent = app.F1OptimizerUIFigure; 
        app.Panel_info_2.Units = 'normalized';
        app.Panel_info_2.Position = [0.1, 0.05, 0.8, 0.86]; 
        app.XButton_2.Parent = app.Panel_info_2;
        % Posición de la X
        figPos = app.F1OptimizerUIFigure.Position;
        panelAncho = figPos(3) * 0.8;
        panelAlto  = figPos(4) * 0.86;
        app.XButton_2.Position = [panelAncho - 53, panelAlto - 25, 52, 25];
        app.Panel_info_2.Visible = 'on';
        app.XButton_2.Visible = 'on';
        uistack(app.Panel_info_2, 'top');
        end
end

    %% Función para circuitos procesados
    methods (Access = private)
    function actualizarCircuitosProcesadosTab(app)

        pathToApp = fileparts(mfilename('fullpath'));
        if strcmp(app.TemporadaSwitch.Value, '2026')
            datosFolder = fullfile(pathToApp, 'datos_2026');
        else
            datosFolder = fullfile(pathToApp, 'datos');
        end
        todosCircuitos = {'australia', 'austria', 'barcelona', 'barein', 'canada', 'interlagos', 'monaco', 'monza', 'silverstone', 'singapur', 'spa', 'suzuka'};
        circuitosProcesados = {};
        for i = 1:length(todosCircuitos)
            circ = todosCircuitos{i};
            if isfile(fullfile(datosFolder, sprintf('laps_%s.mat', circ))) && ...
               isfile(fullfile(datosFolder, sprintf('circuit_%s.mat', circ))) && ...
               isfile(fullfile(datosFolder, sprintf('telemetry_%s.mat', circ)))
               circuitosProcesados{end+1} = circ;
            end
        end
        % Ocultar todo primero
        app.PanelAustralia_2.Visible = 'off';
        app.PanelAustria_2.Visible = 'off';
        app.PanelBarcelona_2.Visible = 'off';
        app.PanelBarein_2.Visible = 'off';
        app.PanelCanada_2.Visible = 'off';
        app.PanelInterlagos_2.Visible = 'off';
        app.PanelMonaco_2.Visible = 'off';
        app.PanelMonza_2.Visible = 'off';
        app.PanelSilverstone_2.Visible = 'off';
        app.PanelSingapur_2.Visible = 'off';
        app.PanelSpa_2.Visible = 'off';
        app.PanelSuzuka_2.Visible = 'off';
        % Mostrar procesados
        nCols = 4;
        for i = 1:length(circuitosProcesados)
            circ = circuitosProcesados{i};
            switch circ
                case 'australia', panel = app.PanelAustralia_2; label = app.vueltasLabel_24;
                case 'austria', panel = app.PanelAustria_2; label = app.vueltasLabel_23;
                case 'barcelona', panel = app.PanelBarcelona_2; label = app.vueltasLabel_22;
                case 'barein', panel = app.PanelBarein_2; label = app.vueltasLabel_21;
                case 'canada', panel = app.PanelCanada_2; label = app.vueltasLabel_20;
                case 'interlagos', panel = app.PanelInterlagos_2; label = app.vueltasLabel_19;
                case 'monaco', panel = app.PanelMonaco_2; label = app.vueltasLabel_18;
                case 'monza', panel = app.PanelMonza_2; label = app.vueltasLabel_17;
                case 'silverstone', panel = app.PanelSilverstone_2; label = app.vueltasLabel_16;
                case 'singapur', panel = app.PanelSingapur_2; label = app.vueltasLabel_15;
                case 'spa', panel = app.PanelSpa_2; label = app.vueltasLabel_14;
                case 'suzuka', panel = app.PanelSuzuka_2; label = app.vueltasLabel_13;
            end
            try
                data = load(fullfile(datosFolder, sprintf('laps_%s.mat', circ)), 'laps');
                lapsD = data.laps.lap_data;
                nVueltas = size(lapsD, 1);
                nSetups = size(unique(lapsD(:, 4:5), 'rows'), 1);
                label.Text = {
                    sprintf('Vueltas Totales: %d', nVueltas);
                    sprintf('Setups Probados: %d', nSetups)
                };
            catch
                label.Text = 'Error';
            end
            fila = floor((i - 1) / nCols) + 2;
            columna = mod(i - 1, nCols) + 1;
            panel.Layout.Row = fila;
            panel.Layout.Column = columna;
            panel.Visible = 'on';
        end
    end
    end

    %% Función Optimizador
    methods (Access = private)
    function actualizarSelectorOptimizador(app)

        mapa = containers.Map();
        % Nombres archivos
        mapa('australia') = {'Albert Park', 'Australia'};
        mapa('austria') = {'Red Bull Ring', 'Austria'};
        mapa('barcelona') = {'Barcelona', 'Spain'};
        mapa('barein') = {'Barein', 'Bahrain'};      
        mapa('canada') = {'Gilles Villeneuve', 'Canada'};
        mapa('interlagos') = {'Interlagos', 'Brazil'};
        mapa('monaco') = {'Monaco', 'Monaco'};
        mapa('monza') = {'Monza', 'Italy'};
        mapa('silverstone') = {'Silverstone', 'the_United_Kingdom'};
        mapa('singapur') = {'Marina Bay', 'Singapore'};
        mapa('spa') = {'Spa', 'Belgium'};
        mapa('suzuka') = {'Suzuka', 'Japan'};              
    % Carpeta de datos
    temp = app.TemporadaSwitch.Value;
        if strcmp(temp, '2026')
            folderDatos = fullfile(fileparts(mfilename('fullpath')), 'datos_2026');
        else
            folderDatos = fullfile(fileparts(mfilename('fullpath')), 'datos');
        end 
        % Circuitos procesados
        if ~exist(folderDatos, 'dir'), return; end 
        files = dir(fullfile(folderDatos, 'circuit_*.mat'));
        circuitosList = {};
        for i = 1:length(files)
            nombreBase = strrep(strrep(files(i).name, 'circuit_', ''), '.mat', '');
            if exist(fullfile(folderDatos, ['laps_' nombreBase '.mat']), 'file') && ...
               exist(fullfile(folderDatos, ['telemetry_' nombreBase '.mat']), 'file')
                circuitosList{end+1} = nombreBase;
            end
        end
        delete(app.GridSelectorOptimizador.Children);
        app.GridSelectorOptimizador.Scrollable = 'on';
        % Tamaño Grid
        n = length(circuitosList);
        app.GridSelectorOptimizador.RowHeight = repmat({130}, 1, n);
        app.GridSelectorOptimizador.ColumnWidth = {'1x', '0.3x'};
        % Poner fotos
        for i = 1:n
            circ = lower(circuitosList{i}); 
            fila = i;
            if isKey(mapa, circ)
                info = mapa(circ);
                nombreLayout = info{1};   
                nombreBandera = info{2};  
            else
                parts = split(circ, '_');
                parts = cellfun(@(x) [upper(x(1)) x(2:end)], parts, 'UniformOutput', false);
                nombreLayout = strjoin(parts, '_'); 
                nombreBandera = nombreLayout;
            end
            infoTooltip = nombreLayout; 
        try
            dC = load(fullfile(folderDatos, ['circuit_' circ '.mat']), 'circuit_data');
            if isfield(dC.circuit_data, 'name')
                nombreMostrar = dC.circuit_data.name;
            else
                nombreMostrar = nombreLayout;
            end
            curv = abs(dC.circuit_data.curvature);
            len = length(curv);
            rectas = sum(curv < 0.002)/len * 100;
            rapidas = sum(curv >= 0.002 & curv < 0.006)/len * 100;
            lentas = sum(curv >= 0.006)/len * 100;
            infoTooltip = sprintf('%s:\n\nRectas: %.1f%%\nCurvas Rápidas: %.1f%%\nCurvas Lentas: %.1f%%', ...
                          nombreMostrar, rectas, rapidas, lentas);
        catch
        end
            % Foto circuito (Columna 1)
            imgTrace = uiimage(app.GridSelectorOptimizador);
            imgTrace.Layout.Row = fila;
            imgTrace.Layout.Column = 1;
            imgTrace.ScaleMethod = 'fit';
            imgTrace.BackgroundColor = [0.13 0.13 0.13];
            infoTooltip = strrep(infoTooltip, 'Barein', 'Baréin');
            imgTrace.Tooltip = infoTooltip;
            rutaTrazado = fullfile('imagenes', [nombreLayout '_layout_HD.png']);
            if exist(rutaTrazado, 'file')
                imgTrace.ImageSource = rutaTrazado;
            else
                imgTrace.AltText = nombreLayout;
            end
            % Foto bandera (columna 2)
            imgFlag = uiimage(app.GridSelectorOptimizador);
            imgFlag.Layout.Row = fila;
            imgFlag.Layout.Column = 2;
            imgFlag.ScaleMethod = 'fit';
            rutaBand = fullfile('imagenes', ['Flag_of_' nombreBandera '.png']);
            if exist(rutaBand, 'file')
                imgFlag.ImageSource = rutaBand;
            end
            % Click en la bandera
            imgFlag.ImageClickedFcn = @(src, event) procesarSeleccion(app, circ, nombreLayout);
        end
    end

      %% Función Procesar Selección
      function procesarSeleccion(app, circuito, nombreMostrar)

        % Barra de carga 
        nombreCorregido = nombreMostrar;
        if strcmpi(nombreCorregido, 'Monaco'), nombreCorregido = 'Mónaco'; end
        if strcmpi(nombreCorregido, 'Barein'), nombreCorregido = 'Baréin'; end
        d = uiprogressdlg(app.F1OptimizerUIFigure, 'Indeterminate', 'on', 'Message', ['Optimizando ' nombreCorregido '...']);
        try
        % Cálculo
        datos = optimizar_setup(fileparts(mfilename('fullpath')), circuito, app.TemporadaSwitch.Value);       
        datos.Circuito = circuito;
        datos.NombreCompleto = nombreMostrar;
        close(d);
        % Gráficas
        actualizarGrafica3D(app, datos); 
        actualizarTablaResultados(app, datos);
        catch ME
        close(d);
        uialert(app.F1OptimizerUIFigure, ME.message, 'Error');
        end
      end

      %% Función Tabla Resultados
      function actualizarTablaResultados(app, datos)
         
         app.PanelCentral.UserData = datos;
         delete(app.PanelCentral.Children)
         g = uigridlayout(app.PanelCentral);
         g.ColumnWidth = {'1x'};
         g.RowHeight = {25,'0.75x', '0.05x', '0.6x'};
         g.RowSpacing = 5;
         g.Padding = [10 10 10 10]; 
         g.BackgroundColor = [0.13 0.13 0.13];
         % Nombre circuito
         lblTitle = uilabel(g);
         textoTitulo = datos.NombreCompleto;
         if contains(textoTitulo, 'Monaco', 'IgnoreCase', true), textoTitulo = 'Mónaco'; end
         if contains(textoTitulo, 'Barein', 'IgnoreCase', true), textoTitulo = 'Baréin'; end
         lblTitle.Text = textoTitulo; 
         lblTitle.Layout.Row = 1;
         lblTitle.HorizontalAlignment = 'center';
         lblTitle.VerticalAlignment = 'center';
         lblTitle.FontColor = [1 1 1];
         lblTitle.FontSize = 18;      
         lblTitle.FontWeight = 'bold';

         res = datos.TopSetups; 
         nShow = min(15, size(res, 1)); 
         topData = res(1:nShow, :);
         Pos = (1:nShow)';
         FW = topData(:, 1);
         RW = topData(:, 2);
         if strcmp(app.TemporadaSwitch.Value, '2026')
             BalanceNum = FW - RW - 10;
         else
             BalanceNum = FW - RW;
         end
         Balance = compose('%+d', BalanceNum); 
         Z_Score = topData(:, 3);
         Tiempo = compose('%.3f', topData(:, 4));
         % Crear Tabla
         t = uitable(g);
         % Colores tabla
         t.BackgroundColor = [0.13 0.13 0.13 ; 0.16 0.16 0.16];
         t.RowStriping = 'on'; 
         % Formato Datos
         t.Data = table(Pos, FW, RW, Balance, Z_Score, Tiempo);
         t.ColumnName = {'Posición', 'FW (º)', 'RW (º)', 'Balance', 'Z-Score', 'Tiempo (s)'};
         % Estilos
         t.ColumnWidth = {80, 90, 90, 80, '1x'};
         t.RowName = {};
         t.SelectionType = 'row';
         t.Multiselect = 'on';
         t.FontSize = 18;
         sNormal = uistyle('HorizontalAlignment', 'center', 'FontColor', 'w');
         addStyle(t, sNormal);
         sPos = uistyle('BackgroundColor', [0.09 0.09 0.09], 'FontWeight', 'bold');
         addStyle(t, sPos, 'column', 1);
         % Resaltar Top 3
         addStyle(t, uistyle('FontColor',[1 0.8 0]), 'row', 1); 
         addStyle(t, uistyle('FontColor',[0.8 0.8 0.8]), 'row', 2); 
         addStyle(t, uistyle('FontColor',[0.8 0.5 0.2]), 'row', 3);
         % Mejor setup probado
         realFW = datos.BestRealSetup(1);
         realRW = datos.BestRealSetup(2);
         realTime = datos.BestRealTime;
         % Buscar en el ranking
         res = datos.TopSetups;
         idxRanking = find(res(:,1)==realFW & res(:,2)==realRW, 1);
         if isempty(idxRanking), strRanking = 'N/A'; else, strRanking = sprintf('#%d', idxRanking); end
         lblBest = uilabel(g);
         lblBest.Layout.Row = 3;
         lblBest.Text = sprintf('Mejor Setup Probado: FW %dº | RW %dº (Ranking: %s), Tiempo: %.3fs', realFW, realRW, strRanking, realTime);  
         lblBest.HorizontalAlignment = 'center';
         lblBest.FontColor = [1 1 1];
         lblBest.FontWeight = 'bold';
         lblBest.FontSize = 16;
         % Mensaje debajo
         lblInfo = uilabel(g);
         lblInfo.Layout.Row = 4;
         lblInfo.Text = 'Seleccionar dos setups (CTRL + clic izq.) para compararlos';
         lblInfo.HorizontalAlignment = 'center';
         lblInfo.VerticalAlignment = 'center';
         lblInfo.FontColor = [0.5 0.5 0.5];
         lblInfo.FontSize = 20;
         lblInfo.Tag = 'PlaceholderMetricas'; 
         % Clic en fila
         t.UserData = datos; 
         t.CellSelectionCallback = @(src, event) filaSeleccionada(app, src, event, g);
      end

        %% Gráficas
        function actualizarGrafica3D(app, datos, targetRW)
  
                if nargin < 3
                    [~, idx] = min(datos.TopSetups(:, 4));
                    targetRW = datos.TopSetups(idx, 2);
                end

                circuitoID = lower(datos.Circuito);
                switch circuitoID
                case 'australia', tema=[0,0.1,0.6]; mapa3D=@turbo;
                case 'austria', tema=[0.6,0,0]; mapa3D=@hot;
                case 'barcelona', tema=[1,0.8,0]; mapa3D=@spring;
                case 'barein', tema=[0.6,0.4,0.2]; mapa3D=@copper;
                case 'canada', tema=[0.4,0.8,1]; mapa3D=@winter;
                case 'interlagos', tema=[0,0.5,0]; mapa3D=@parula;
                case 'monaco', tema=[0.85,0.65,0.1]; mapa3D=@pink;
                case 'monza', tema=[0,0.4,0]; mapa3D=@summer;
                case 'silverstone', tema=[0.6,0.6,0.6]; mapa3D=@gray;
                case 'singapur', tema=[0.7,0.4,0.9]; mapa3D=@cool;
                case 'spa', tema=[1,0.5,0]; mapa3D=@autumn;
                case 'suzuka', tema=[0.9,0.9,0.9]; mapa3D=@bone;
                otherwise, tema=[0,0.8,1]; mapa3D=@parula;
                end

            delete(app.PanelDerecho.Children);
            g = uigridlayout(app.PanelDerecho);
            g.RowHeight = {'1x', '1x'};
            g.ColumnWidth = {'1x'};
            g.BackgroundColor = [0.13 0.13 0.13];
            % Gráfica 1
            ax3D = uiaxes(g);
            ax3D.Layout.Row = 1;
            ax3D.BackgroundColor = [0.13 0.13 0.13];
            ax3D.XColor='w'; ax3D.YColor='w'; ax3D.ZColor='w';
            res = datos.TopSetups;
            x = res(:,1); y = res(:,2); z = res(:,4);
            scatter3(ax3D, x, y, z, 80, z, 'filled');
            colormap(ax3D, flipud(mapa3D(100))); 
            title(ax3D, 'Mapa de Rendimiento', 'Color', 'w', 'FontSize', 18);
            xlabel(ax3D, 'FW (º)', 'FontSize', 14); 
            ylabel(ax3D, 'RW (º)', 'FontSize', 14); 
            zlabel(ax3D, 'Tiempo (s)', 'FontSize', 14);
            view(ax3D, -20, 10);
            grid(ax3D, 'on'); ax3D.GridColor = [0.3 0.3 0.3];
            % Gráfica 2
            ax2D = uiaxes(g);
            ax2D.Layout.Row = 2;
            ax2D.BackgroundColor = [0.13 0.13 0.13];
            ax2D.XColor='w'; ax2D.YColor='w';
            mask = (res(:,2) == targetRW);
            dataSlice = res(mask, :);
            if ~isempty(dataSlice)
                [~, ord] = sort(dataSlice(:,1)); 
                dataSlice = dataSlice(ord, :);
                 xx = dataSlice(:,1);
                yy = dataSlice(:,4);
                plot(ax2D, xx, yy, 'o-', 'LineWidth', 2, 'Color', tema, 'MarkerFaceColor', tema);
                title(ax2D, sprintf('Sensibilidad FW con RW=%dº', targetRW), 'Color', 'w', 'FontSize', 18);
                xlabel(ax2D, 'FW (º)', 'FontSize', 14); 
                ylabel(ax2D, 'Tiempo Vuelta (s)', 'FontSize', 14);
                grid(ax2D, 'on'); ax2D.GridColor = [0.3 0.3 0.3];
                if min(xx) < max(xx)
                   xlim(ax2D, [min(xx) max(xx)]);
                end
            else
                title(ax2D, sprintf('Sin datos para RW=%d (º)', targetRW), 'Color', 'r');
            end
        end

        %% Función para clic en la tabla
        function filaSeleccionada(app, src, event, parentGrid)
            
            if isempty(event.Indices), return; end
            filas = unique(event.Indices(:,1));
            nSel = length(filas);
            datos = src.UserData; 
            topData = datos.TopSetups;
            if nSel == 1
                idx = filas(1);
                % Actualiza gráfica derecha
                actualizarGrafica3D(app, datos, topData(idx, 2));
            elseif nSel == 2
                idxA = filas(1); 
                idxB = filas(2);
                setupA = topData(idxA, :);
                setupB = topData(idxB, :);
                titulo = sprintf('Comparativa: Setup #%d vs. Setup #%d  (Verde: setup #%d mejor)', idxA, idxB, idxA);
                % Actualiza Panel Inferior (Métricas)
                actualizarPanelMetricas(app, parentGrid, setupA, setupB, titulo);
            end 
        end

        %% Función para hacer gráficas de métricas
           function actualizarPanelMetricas(~, parentGrid, actual, ref, tituloComparacion)

            hijos = parentGrid.Children;
            for i=length(hijos):-1:1 
                tag = hijos(i).Tag;
                if strcmp(tag, 'PanelMetricas') || strcmp(tag, 'PlaceholderMetricas')
                    delete(hijos(i)); 
                end
            end
            % Grid métricas
            gM = uigridlayout(parentGrid);
            gM.Layout.Row = 4; 
            gM.ColumnWidth = {'1x'}; 
            gM.RowHeight = {30, '1x'}; 
            gM.Padding = [10 5 35 5]; 
            gM.BackgroundColor = [0.13 0.13 0.13];
            gM.Tag = 'PanelMetricas';
            % Título 
            lblT = uilabel(gM);
            lblT.Layout.Row = 1;
            lblT.Text = tituloComparacion;
            lblT.HorizontalAlignment = 'center';
            lblT.FontColor = [0.9 0.9 0.9];
            lblT.FontSize = 16;  
            lblT.FontWeight = 'normal';
            % Gráfica
            ax = uiaxes(gM);
            ax.Layout.Row = 2;
            ax.Layout.Column = 1;
            ax.BackgroundColor = [0.13 0.13 0.13];
            ax.XColor = 'w'; ax.YColor = 'w';
            ax.GridColor = [0.3 0.3 0.3];
            ax.XGrid = 'off'; ax.YGrid = 'on';
            nombres = {'Velocidad Máx.', 'G Lateral', 'G Long. Aceleración', 'G Long. Freno',  '% Acelerador', '% Freno', 'Var. Dirección'};
            indices = 5:11;
            valsAct = actual(indices);
            valsRef = ref(indices);
            deltas = ((valsAct - valsRef) ./ valsRef) * 100;
            deltas(6) = -deltas(6); 
            deltas(7) = -deltas(7); 
            b = bar(ax, deltas, 0.4, 'FaceColor', 'flat');
            for k = 1:7
                if deltas(k) >= 0
                    b.CData(k,:) = [0 0.75 0.05]; 
                else
                    b.CData(k,:) = [0.75 0 0]; 
                end
            end
            ylabel(ax, '% Mejora', 'FontSize', 10, 'FontWeight', 'normal'); 
            xticklabels(ax, nombres);
            ax.XAxis.FontSize = 10; 
            ax.XAxis.FontWeight = 'normal';
            ax.YAxis.TickLabelFormat = '%+g%%';
            ax.YAxis.FontSize = 10;
            yline(ax, 0, 'w-', 'LineWidth', 1.5);
            xtips = b.XEndPoints;
            ytips = b.YEndPoints;
            labels = string(round(deltas, 1)) + "%";
            for k=1:7
               offset = max(abs(deltas))*0.1;
               if offset==0, offset=1; end
               if deltas(k) < 0, offset = -offset; end
               text(ax, xtips(k), ytips(k) + offset, labels(k), 'HorizontalAlignment', 'center', 'Color', 'w', 'FontSize', 10, 'FontWeight', 'normal'); 
            end
            lim = max(abs(deltas));
            if lim < 1, lim = 1; end
            ylim(ax, [-lim*1.5, lim*1.5]);
           end

        %% Función para exportar datos        
        function exportarResultados(app)
            % Recuperar datos
            datos = app.PanelCentral.UserData;
            if isempty(datos)
                uialert(app.F1OptimizerUIFigure, 'No hay datos', 'Aviso');
                return;
            end
            nombreArchivo = sprintf('Ranking_%s.xlsx', datos.NombreCompleto);
            [file, path] = uiputfile('*.xlsx', 'Guardar Ranking', nombreArchivo);
            if isequal(file, 0), return; end
            fichero = fullfile(path, file);
            % Ranking en Excel
            topData = datos.TopSetups;
            if strcmp(app.TemporadaSwitch.Value, '2026')
                Balance = topData(:,1) - topData(:,2) - 10;
            else
                Balance = topData(:,1) - topData(:,2);
            end
             T = table((1:size(topData,1))', topData(:,1), topData(:,2), Balance, topData(:,3), topData(:,4), topData(:,5), topData(:,6), topData(:,7), topData(:,8), topData(:,9)*100, topData(:,10)*100, topData(:,11), ...
                'VariableNames', {'Posición', 'FW (º)', 'RW (º)', 'Balance', 'Z Score', 'Tiempo Estimado (s)', 'Velocidad máxima (km/h)', 'G Lateral media en curva (g)', 'G Longitudinal media en aceleración (g)', 'G Longitudinal media en frenada (g)', '% de vuelta acelerando (%)', '% de la vuelta frenando (%)', 'Variabilidad de la dirección (º)'});
            writetable(T, fichero, 'Sheet', 'Datos', 'Range', 'A4');
            % Cabeceras
            nombreExcel = datos.NombreCompleto;
            if contains(nombreExcel, 'Monaco', 'IgnoreCase', true), nombreExcel = 'Mónaco'; end
            if contains(nombreExcel, 'Barein', 'IgnoreCase', true), nombreExcel = 'Baréin'; end
            writecell({nombreExcel}, fichero, 'Sheet', 'Datos', 'Range', 'A1');
            infoReal = sprintf('Mejor Real: FW %dº / RW %dº - Tiempo: %.3fs', datos.BestRealSetup(1), datos.BestRealSetup(2), datos.BestRealTime);writecell({infoReal}, fichero, 'Sheet', 'Datos', 'Range', 'A2');
            % Centrar
            try
                excel = actxserver('Excel.Application');
                libro = excel.Workbooks.Open(fichero);
                hoja = libro.Sheets.Item('Datos');
                hoja.UsedRange.HorizontalAlignment = -4108;
                libro.Save(); libro.Close(); excel.Quit(); delete(excel);
            catch
            end
            uialert(app.F1OptimizerUIFigure, 'Ranking exportado', ' ', 'Icon', 'info');
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonAustralia
        function ButtonAustraliaPushed(app, event)
            pathToApp = fileparts(mfilename('fullpath'));
            % Cargar datos de Australia
            app.nombrecircuitoLabel_2.Text = 'Circuito de Albert Park';
            app.numerosLabel_2.Text = {'Longitud: 5,303 km'; ''; 'Curvas: 16'; ''; 'Vuelta récord: 1:19.813 (Charles Leclerc)'; ''; 'Inauguración: 07 de marzo de 1996'; ''; 'Eventos: F1, V8 Supercars'};
            app.datoLabel_2.Text = 'El circuito se construye cada año alrededor del lago Albert Park, utilizando carreteras públicas que se cierran para el evento.';
            app.TraccinLabel_2.Text = 'Tracción:                            ● ● ○ ○ ○';
            app.FrenadasLabel_2.Text = 'Frenadas:                          ● ● ○ ○ ○';
            app.GLateralLabel_2.Text = 'G Lateral:                          ● ● ● ○ ○';
            app.DownforceLabel_2.Text = 'Downforce:                        ● ● ○ ○ ○';
            app.Image_2.ImageSource = fullfile(pathToApp, 'imagenes', 'Albert Park_layout_HD.png');
            % Mostrar popup
            app.Panel_info.Visible = 'on';
            app.XButton.Visible = 'on';
            drawnow
            uistack(app.Panel_info, 'top');
            uistack(app.XButton, 'top');
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.Panel_info.Visible = 'off';
            app.XButton.Visible = 'off'; 
        end

        % Button pushed function: ButtonAustria
        function ButtonAustriaPushed(app, event)
            pathToApp = fileparts(mfilename('fullpath'));
            % Cargar datos de Austria
            app.nombrecircuitoLabel_2.Text = 'Red Bull Ring';
            app.numerosLabel_2.Text = {'Longitud: 4,318 km'; ''; 'Curvas: 10'; ''; 'Vuelta récord: 1:05.619 (Carlos Sainz)'; ''; 'Inauguración: 26 de julio de 1969'; ''; 'Eventos: F1, MotoGP, GT, DTM'};
            app.datoLabel_2.Text = 'En el centro del circuito hay un toro de acero de 19 metros de altura, símbolo de la marca de bebidas energéticas.';
            app.TraccinLabel_2.Text = 'Tracción:                            ● ● ○ ○ ○';
            app.FrenadasLabel_2.Text = 'Frenadas:                          ● ● ● ○ ○';
            app.GLateralLabel_2.Text = 'G Lateral:                          ● ● ● ○ ○';
            app.DownforceLabel_2.Text = 'Downforce:                        ● ● ○ ○ ○';
            app.Image_2.ImageSource = fullfile(pathToApp, 'imagenes', 'Red Bull Ring_layout_HD.png');
            % Mostrar popup
            app.Panel_info.Visible = 'on';
            app.XButton.Visible = 'on';
            drawnow
            uistack(app.Panel_info, 'top');
            uistack(app.XButton, 'top');   
        end

        % Button pushed function: ButtonBarcelona
        function ButtonBarcelonaPushed(app, event)
            pathToApp = fileparts(mfilename('fullpath'));
            app.nombrecircuitoLabel_2.Text = 'Circuit de Barcelona-Catalunya';
            app.numerosLabel_2.Text = {'Longitud: 4,675 km'; ''; 'Curvas: 14'; ''; 'Vuelta récord: 1:14.637 (Michael Schumacher)'; ''; 'Inauguración: 10 de septiembre de 1991'; ''; 'Eventos: F1, MotoGP, ELMS, CER-GT, Turismos'};
            app.datoLabel_2.Text = 'Situado en la recta principal, junto a la salida del pit lane, se encuentra un gran marcador inspirado en el que existe en el circuito de Indianápolis.';
            app.TraccinLabel_2.Text = 'Tracción:                            ● ● ● ○ ○';
            app.FrenadasLabel_2.Text = 'Frenadas:                          ● ● ● ○ ○';
            app.GLateralLabel_2.Text = 'G Lateral:                          ● ● ● ● ●';
            app.DownforceLabel_2.Text = 'Downforce:                        ● ● ● ● ○';
            app.Image_2.ImageSource = fullfile(pathToApp, 'imagenes', 'Barcelona_layout_HD.png');
            app.Panel_info.Visible = 'on';
            app.XButton.Visible = 'on';
            uistack(app.Panel_info, 'top');
            uistack(app.XButton, 'top');
        end

        % Button pushed function: ButtonBarein
        function ButtonBareinPushed(app, event)
            pathToApp = fileparts(mfilename('fullpath'));
            app.nombrecircuitoLabel_2.Text = 'Circuito Internacional de Baréin';
            app.numerosLabel_2.Text = {'Longitud: 5,412 km'; ''; 'Curvas: 15'; ''; 'Vuelta récord: 1:31.447 (Pedro de la Rosa)'; ''; 'Inauguración: 17 de marzo de 2004'; ''; 'Eventos: F1, WEC'};
            app.datoLabel_2.Text = 'El Gran Premio de Baréin de 2005 ostenta el récord histórico de la carrera más calurosa de la Fórmula 1, con una temperatura ambiente de 42.6°C y más de 56°C en pista.';
            app.TraccinLabel_2.Text = 'Tracción:                            ● ● ● ● ○';
            app.FrenadasLabel_2.Text = 'Frenadas:                          ● ● ● ● ○';
            app.GLateralLabel_2.Text = 'G Lateral:                          ● ● ● ○ ○';
            app.DownforceLabel_2.Text = 'Downforce:                        ● ● ● ○ ○';
            app.Image_2.ImageSource = fullfile(pathToApp, 'imagenes', 'Barein_layout_HD.png');
            drawnow;
            app.Panel_info.Visible = 'on';
            app.XButton.Visible = 'on';
            uistack(app.Panel_info, 'top');
            uistack(app.XButton, 'top');
        end

        % Button pushed function: ButtonCanada
        function ButtonCanadaPushed(app, event)
            pathToApp = fileparts(mfilename('fullpath'));
            app.nombrecircuitoLabel_2.Text = 'Circuito Gilles Villeneuve';
            app.numerosLabel_2.Text = {'Longitud: 4,361 km'; ''; 'Curvas: 14'; ''; 'Vuelta récord: 1:13.078 (Valtteri Bottas)'; ''; 'Inauguración: 8 de octubre de 1978'; ''; 'Eventos: F1, IndyCar'};
            app.datoLabel_2.Text = 'En la última chicane se encuentra el "Muro de los Campeones", bautizado en 1999 cuando tres campeones mundiales chocaron allí en el mismo fin de semana. ';
            app.TraccinLabel_2.Text = 'Tracción:                            ● ● ● ● ○';
            app.FrenadasLabel_2.Text = 'Frenadas:                          ● ● ● ● ●';
            app.GLateralLabel_2.Text = 'G Lateral:                          ● ○ ○ ○ ○';
            app.DownforceLabel_2.Text = 'Downforce:                        ● ● ● ○ ○';
            app.Image_2.ImageSource = fullfile(pathToApp, 'imagenes', 'Gilles Villeneuve_layout_HD.png');
            drawnow;
            app.Panel_info.Visible = 'on';
            app.XButton.Visible = 'on';
            uistack(app.Panel_info, 'top');
            uistack(app.XButton, 'top');
        end

        % Button pushed function: ButtonInterlagos
        function ButtonInterlagosPushed(app, event)
            pathToApp = fileparts(mfilename('fullpath'));
            app.nombrecircuitoLabel_2.Text = 'Autódromo José Carlos Pace';
            app.numerosLabel_2.Text = {'Longitud: 4,309 km'; ''; 'Curvas: 15'; ''; 'Vuelta récord: 1:10.540 (Valtteri Bottas)'; ''; 'Inauguración: 12 de mayo de 1940 '; ''; 'Eventos: F1, Stock Car Brasil'};
            app.datoLabel_2.Text = 'En este circuito, en 1991, Senna logró una de sus victorias más memorables al ganar la carrera con problemas en la caja de cambios, terminando con solo la sexta velocidad disponible.';
            app.TraccinLabel_2.Text = 'Tracción:                            ● ● ● ○ ○';
            app.FrenadasLabel_2.Text = 'Frenadas:                          ● ● ● ○ ○';
            app.GLateralLabel_2.Text = 'G Lateral:                          ● ● ● ○ ○';
            app.DownforceLabel_2.Text = 'Downforce:                        ● ● ● ● ○';
            app.Image_2.ImageSource = fullfile(pathToApp, 'imagenes', 'Interlagos_layout_HD.png');
            drawnow;
            app.Panel_info.Visible = 'on';
            app.XButton.Visible = 'on';
            uistack(app.Panel_info, 'top');
            uistack(app.XButton, 'top');
        end

        % Button pushed function: ButtonMonaco
        function ButtonMonacoPushed(app, event)
            pathToApp = fileparts(mfilename('fullpath'));
            app.nombrecircuitoLabel_2.Text = 'Circuito de Mónaco';
            app.numerosLabel_2.Text = {'Longitud: 3,337 km'; ''; 'Curvas: 19'; ''; 'Vuelta récord: 1:12.909 (Lewis Hamilton)'; ''; 'Inauguración: 14 de abril de 1929'; ''; 'Eventos: F1, Fórmula E'};
            app.datoLabel_2.Text = 'La horquilla de Fairmont (antes Loews) es tan cerrada que los pilotos deben modificar la dirección de sus volantes para pasarla.';
            app.TraccinLabel_2.Text = 'Tracción:                            ● ● ● ● ●';
            app.FrenadasLabel_2.Text = 'Frenadas:                          ● ● ○ ○ ○';
            app.GLateralLabel_2.Text = 'G Lateral:                          ● ○ ○ ○ ○';
            app.DownforceLabel_2.Text = 'Downforce:                        ● ● ● ● ●';
            app.Image_2.ImageSource = fullfile(pathToApp, 'imagenes', 'Monaco_layout_HD.png');
            drawnow;
            app.Panel_info.Visible = 'on';
            app.XButton.Visible = 'on';
            uistack(app.Panel_info, 'top');
            uistack(app.XButton, 'top');
        end

        % Button pushed function: ButtonMonza
        function ButtonMonzaPushed(app, event)
            pathToApp = fileparts(mfilename('fullpath'));
            app.nombrecircuitoLabel_2.Text = 'Autodromo Nazionale di Monza';
            app.numerosLabel_2.Text = {'Longitud: 5,793 km'; ''; 'Curvas: 11'; ''; 'Vuelta récord: 1:20.901 (Lando Norris)'; ''; 'Inauguración: 3 de septiembre de 1922'; ''; 'Eventos: F1, WTCR, WEC'};
            app.datoLabel_2.Text = 'Es conocido como el "Templo de la Velocidad". Fue construido en solo 110 días hace más de 100 años, siendo el tercer circuito permanente más antiguo del mundo.';
            app.TraccinLabel_2.Text = 'Tracción:                            ● ● ● ○ ○';
            app.FrenadasLabel_2.Text = 'Frenadas:                          ● ● ● ● ●';
            app.GLateralLabel_2.Text = 'G Lateral:                          ● ● ○ ○ ○';
            app.DownforceLabel_2.Text = 'Downforce:                        ● ○ ○ ○ ○';
            app.Image_2.ImageSource = fullfile(pathToApp, 'imagenes', 'Monza_layout_HD.png');
            drawnow;
            app.Panel_info.Visible = 'on';
            app.XButton.Visible = 'on';
            uistack(app.Panel_info, 'top');
            uistack(app.XButton, 'top');
        end

        % Button pushed function: ButtonSilverstone
        function ButtonSilverstonePushed(app, event)
            pathToApp = fileparts(mfilename('fullpath'));
            app.nombrecircuitoLabel_2.Text = 'Circuito de Silverstone';
            app.numerosLabel_2.Text = {'Longitud: 5,901 km'; ''; 'Curvas: 18'; ''; 'Vuelta récord: 1:27.097 (Max Verstappen)'; ''; 'Inauguración: 1 de septiembre de 1947'; ''; 'Eventos: F1, MotoGP, WEC, BTCC, WRX'};
            app.datoLabel_2.Text = 'Sede de la primera carrera de F1 de la historia el 13 de mayo de 1950. Está construido sobre un antiguo aeródromo de la Segunda Guerra Mundial.';
            app.TraccinLabel_2.Text = 'Tracción:                            ● ● ● ○ ○';
            app.FrenadasLabel_2.Text = 'Frenadas:                          ● ● ○ ○ ○';
            app.GLateralLabel_2.Text = 'G Lateral:                          ● ● ● ● ●';
            app.DownforceLabel_2.Text = 'Downforce:                        ● ● ● ● ○';
            app.Image_2.ImageSource = fullfile(pathToApp, 'imagenes', 'Silverstone_layout_HD.png');
            drawnow;
            app.Panel_info.Visible = 'on';
            app.XButton.Visible = 'on';
            uistack(app.Panel_info, 'top');
            uistack(app.XButton, 'top');
        end

        % Button pushed function: ButtonSingapur
        function ButtonSingapurPushed(app, event)
            pathToApp = fileparts(mfilename('fullpath'));
            app.nombrecircuitoLabel_2.Text = 'Marina Bay Street Circuit';
            app.numerosLabel_2.Text = {'Longitud: 4,928 km'; ''; 'Curvas: 19'; ''; 'Vuelta récord: 1:33.808 (Lewis Hamilton)'; ''; 'Inauguración: 28 de septiembre de 2008'; ''; 'Eventos: F1, Porsche Cup Asia, Ferrari Challenge'};
            app.datoLabel_2.Text = 'Fue la primera carrera nocturna de F1. La humedad extrema y el calor hace que sea una de las carreras más exigentes físicamente.';
            app.TraccinLabel_2.Text = 'Tracción:                            ● ● ● ● ○';
            app.FrenadasLabel_2.Text = 'Frenadas:                          ● ● ● ● ●';
            app.GLateralLabel_2.Text = 'G Lateral:                          ● ● ○ ○ ○';
            app.DownforceLabel_2.Text = 'Downforce:                        ● ● ● ● ●';
            app.Image_2.ImageSource = fullfile(pathToApp, 'imagenes', 'Marina Bay_layout_HD.png');
            drawnow;
            app.Panel_info.Visible = 'on';
            app.XButton.Visible = 'on';
            uistack(app.Panel_info, 'top');
            uistack(app.XButton, 'top');
        end

        % Button pushed function: ButtonSpa
        function ButtonSpaPushed(app, event)
            pathToApp = fileparts(mfilename('fullpath'));
            app.nombrecircuitoLabel_2.Text = 'Circuito de Spa-Francorchamps';
            app.numerosLabel_2.Text = {'Longitud: 7,004 km'; ''; 'Curvas: 20'; ''; 'Vuelta récord: 1:44.701 (Sergio Pérez)'; ''; 'Inauguración: Agosto de 1921'; ''; 'Eventos: F1, WEC, 24 Horas, ELMS'};
            app.datoLabel_2.Text = 'Originalmente era un circuito de carretera abierta de 14 km, considerado uno de los más rápidos y peligrosos del mundo. La curva Eau Rouge-Raidillon es una de las más icónicas del automovilismo.';
            app.TraccinLabel_2.Text = 'Tracción:                            ● ● ● ● ○';
            app.FrenadasLabel_2.Text = 'Frenadas:                          ● ● ● ● ○';
            app.GLateralLabel_2.Text = 'G Lateral:                          ● ● ● ● ●';
            app.DownforceLabel_2.Text = 'Downforce:                        ● ● ○ ○ ○';
            app.Image_2.ImageSource = fullfile(pathToApp, 'imagenes', 'Spa_layout_HD.png');
            drawnow;
            app.Panel_info.Visible = 'on';
            app.XButton.Visible = 'on';
            uistack(app.Panel_info, 'top');
            uistack(app.XButton, 'top');
        end

        % Button pushed function: ButtonSuzuka
        function ButtonSuzukaPushed(app, event)
            pathToApp = fileparts(mfilename('fullpath'));
            app.nombrecircuitoLabel_2.Text = 'Circuito de Suzuka';
            app.numerosLabel_2.Text = {'Longitud: 5,807 km'; ''; 'Curvas: 18'; ''; 'Vuelta récord: 1:30.965 (Andrea Kimi Antonelli)'; ''; 'Inauguración: 3 de noviembre de 1962'; ''; 'Eventos: F1, Super Fórmula Japonesa, Super GT'};
            app.datoLabel_2.Text = 'Suzuka ha definido el campeonato mundial de F1 en más de 10 ocasiones, incluyendo los famosos choques entre Senna y Prost en 1989 y 1990.';
            app.TraccinLabel_2.Text = 'Tracción:                            ● ● ● ○ ○';
            app.FrenadasLabel_2.Text = 'Frenadas:                          ● ● ○ ○ ○';
            app.GLateralLabel_2.Text = 'G Lateral:                          ● ● ● ● ●';
            app.DownforceLabel_2.Text = 'Downforce:                        ● ● ● ● ○';
            app.Image_2.ImageSource = fullfile(pathToApp, 'imagenes', 'Suzuka_layout_HD.png');
            drawnow;
            app.Panel_info.Visible = 'on';
            app.XButton.Visible = 'on';
            uistack(app.Panel_info, 'top');
            uistack(app.XButton, 'top');
        end

        % Button pushed function: ProcesarButton
        function ProcesarButtonPushed(app, event)
            pathToApp = fileparts(mfilename('fullpath'));
            % Nombres
            mapaNombres = containers.Map();
            mapaNombres('australia') = 'Albert Park';
            mapaNombres('austria') = 'Red Bull Ring';
            mapaNombres('barcelona') = 'Barcelona';
            mapaNombres('barein') = 'Barein';
            mapaNombres('canada') = 'Gilles Villeneuve';
            mapaNombres('interlagos') = 'Interlagos';
            mapaNombres('monaco') = 'Monaco';
            mapaNombres('monza') = 'Monza';
            mapaNombres('silverstone') = 'Silverstone';
            mapaNombres('singapur') = 'Marina Bay';
            mapaNombres('spa') = 'Spa';
            mapaNombres('suzuka') = 'Suzuka';
            % Leer checkboxes marcados
            circuitos = {};
            if app.SeleccionarCheckBoxAustralia.Value, circuitos{end+1} = 'australia'; end
            if app.SeleccionarCheckBoxAustria.Value, circuitos{end+1} = 'austria'; end
            if app.SeleccionarCheckBoxBarcelona.Value, circuitos{end+1} = 'barcelona'; end
            if app.SeleccionarCheckBoxBarein.Value, circuitos{end+1} = 'barein'; end
            if app.SeleccionarCheckBoxCanada.Value, circuitos{end+1} = 'canada'; end
            if app.SeleccionarCheckBoxInterlagos.Value, circuitos{end+1} = 'interlagos'; end
            if app.SeleccionarCheckBoxMonaco.Value, circuitos{end+1} = 'monaco'; end
            if app.SeleccionarCheckBoxMonza.Value, circuitos{end+1} = 'monza'; end
            if app.SeleccionarCheckBoxSilverstone.Value, circuitos{end+1} = 'silverstone'; end
            if app.SeleccionarCheckBoxSingapur.Value, circuitos{end+1} = 'singapur'; end
            if app.SeleccionarCheckBoxSpa.Value, circuitos{end+1} = 'spa'; end
            if app.SeleccionarCheckBoxSuzuka.Value, circuitos{end+1} = 'suzuka'; end
            if isempty(circuitos)
                uialert(app.F1OptimizerUIFigure, 'Seleccionar al menos un circuito', 'Aviso');
            return;
            end
            % Guardar para usar después
            app.circuitosSeleccionados = circuitos;
            nTotal = length(circuitos);
            % Barra de carga
            d = uiprogressdlg(app.F1OptimizerUIFigure, 'Title', 'Procesando Circuitos', 'Message', 'Iniciando...');
            d.Value = 0;  
            try
            for i = 1:nTotal
            circ = app.circuitosSeleccionados{i};
            % Obtener nombre
            if isKey(mapaNombres, circ)
                nombreMostrar = mapaNombres(circ);
            else
                nombreMostrar = upper(circ);
            end
            if contains(nombreMostrar, 'Monaco', 'IgnoreCase', true), nombreMostrar = 'Mónaco'; end
            if contains(nombreMostrar, 'Barein', 'IgnoreCase', true), nombreMostrar = 'Baréin'; end
            % Mostrar progreso barra
            d.Value = (i - 1) / nTotal;  
            d.Message = sprintf('Procesando %s (%d/%d)...', nombreMostrar, i, nTotal);
            drawnow;  
            procesar_circuito(circ, pathToApp, app.TemporadaSwitch.Value);
            d.Value = i / nTotal; 
            end
            close(d);
            actualizarCircuitosProcesadosTab(app);
            uialert(app.F1OptimizerUIFigure, sprintf('%d Circuitos procesados', nTotal), ...
                'Completado', 'Icon', 'success', ...
                'CloseFcn', @(src, event) set(app.TabGroup, 'SelectedTab', app.CircuitosProcesadosTab));
             catch ME
             if isvalid(d), close(d); end 
             uialert(app.F1OptimizerUIFigure, sprintf('No hay datos'), 'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SelectorCircuitoTab;  
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
        app.Panel_info_2.Visible = 'off'; 
        app.XButton_2.Visible = 'off'; 
        end

        % Button pushed function: ButtonAustralia_2
        function ButtonAustralia_2Pushed(app, event)
        mostrarResultadosCircuito(app, 'australia', 'Circuito de Albert Park');
        end

        % Button pushed function: ButtonAustria_2
        function ButtonAustria_2Pushed(app, event)
        mostrarResultadosCircuito(app, 'austria', 'Red Bull Ring');
        end

        % Button pushed function: ButtonBarcelona_2
        function ButtonBarcelona_2Pushed(app, event)
        mostrarResultadosCircuito(app, 'barcelona', 'Circuit de Barcelona-Catalunya');
        end

        % Button pushed function: ButtonBarein_2
        function ButtonBarein_2Pushed(app, event)
        mostrarResultadosCircuito(app, 'barein', 'Circuito Internacional de Baréin');
        end

        % Button pushed function: ButtonCanada_2
        function ButtonCanada_2Pushed(app, event)
        mostrarResultadosCircuito(app, 'canada', 'Circuito Gilles Villeneuve');
        end

        % Button pushed function: ButtonInterlagos_2
        function ButtonInterlagos_2Pushed(app, event)
        mostrarResultadosCircuito(app, 'interlagos', 'Autódromo José Carlos Pace');
        end

        % Button pushed function: ButtonMonaco_2
        function ButtonMonaco_2Pushed(app, event)
        mostrarResultadosCircuito(app, 'monaco', 'Circuito de Mónaco');
        end

        % Button pushed function: ButtonMonza_2
        function ButtonMonza_2Pushed(app, event)
        mostrarResultadosCircuito(app, 'monza', 'Autodromo Nazionale di Monza');
        end

        % Button pushed function: ButtonSilverstone_2
        function ButtonSilverstone_2Pushed(app, event)
        mostrarResultadosCircuito(app, 'silverstone', 'Circuito de Silverstone');
        end

        % Button pushed function: ButtonSingapur_2
        function ButtonSingapur_2Pushed(app, event)
        mostrarResultadosCircuito(app, 'singapur', 'Marina Bay Street Circuit');
        end

        % Button pushed function: ButtonSpa_2
        function ButtonSpa_2Pushed(app, event)
        mostrarResultadosCircuito(app, 'spa', 'Circuito de Spa-Francorchamps');    
        end

        % Button pushed function: ButtonSuzuka_2
        function ButtonSuzuka_2Pushed(app, event)
        mostrarResultadosCircuito(app, 'suzuka', 'Circuito de Suzuka');    
        end

        % Value changed function: SeleccionarTodosCheckBox
        function SeleccionarTodosCheckBoxValueChanged(app, event)
            value = app.SeleccionarTodosCheckBox.Value;
            % Aplicar el estado a todos los circuitos
            app.SeleccionarCheckBoxAustralia.Value = value;
            app.SeleccionarCheckBoxAustria.Value = value;
            app.SeleccionarCheckBoxBarcelona.Value = value;
            app.SeleccionarCheckBoxBarein.Value = value;
            app.SeleccionarCheckBoxCanada.Value = value;
            app.SeleccionarCheckBoxInterlagos.Value = value;
            app.SeleccionarCheckBoxMonaco.Value = value;
            app.SeleccionarCheckBoxMonza.Value = value;
            app.SeleccionarCheckBoxSilverstone.Value = value;
            app.SeleccionarCheckBoxSingapur.Value = value;
            app.SeleccionarCheckBoxSpa.Value = value;
            app.SeleccionarCheckBoxSuzuka.Value = value;
        end

        % Value changed function: TemporadaSwitch
        function TemporadaSwitchValueChanged(app, event)
            value = app.TemporadaSwitch.Value;
            % Colores
            if strcmp(value, '2026')
                colorNuevo = [0, 0.3, 0.8];    
            else
                colorNuevo = [0.69, 0, 0];    
            end
            % Cambiar switch
            app.TemporadaSwitch.FontColor = colorNuevo;
            % Cambiar paneles 
            listaCircuitos = {'Australia', 'Austria', 'Barcelona', 'Barein', 'Canada', ...
                      'Interlagos', 'Monaco', 'Monza', 'Silverstone', 'Singapur', ...
                      'Spa', 'Suzuka'};
            for i = 1:length(listaCircuitos)
                baseName = ['Panel' listaCircuitos{i}];
                nombres = {baseName, [baseName '_2']};
            for k = 1:length(nombres)
                nombrePanel = nombres{k};
                if isprop(app, nombrePanel)
                    app.(nombrePanel).BorderColor = colorNuevo;     
                    app.(nombrePanel).ForegroundColor = colorNuevo; 
                end
            end
            end
            app.PanelOptimizador.BorderColor = colorNuevo;
            app.PanelCentral.BorderColor = colorNuevo;
            app.PanelDerecho.BorderColor = colorNuevo;
            app.SalirButton.BackgroundColor = colorNuevo;
            % Cambiar banners
            labels = findobj(app.TabGroup, 'Type', 'uilabel');
            for i = 1:length(labels)
                bg = labels(i).BackgroundColor;
                if (bg(1) > 0.5 && bg(3) < 0.3) || (bg(3) > 0.5 && bg(1) < 0.3)
                   labels(i).BackgroundColor = colorNuevo;
                end
            end
            % Recargar datos
            if app.TabGroup.SelectedTab == app.CircuitosProcesadosTab
                actualizarCircuitosProcesadosTab(app);
            elseif app.TabGroup.SelectedTab == app.OptimizadorTab
                actualizarSelectorOptimizador(app);
            end
        end

        % Button pushed function: EntrenarModeloButton
        function EntrenarModeloButtonPushed(app, event)
        % Barra de Carga
        d = uiprogressdlg(app.F1OptimizerUIFigure, 'Title', 'Modelo Machine Learning', ...
            'Message', 'Analizando datos y entrenando modelo...', ...
            'Indeterminate', 'on');
        try
        pat = fileparts(mfilename('fullpath'));
        temp = app.TemporadaSwitch.Value;
        res = entrenar_modelo(pat, temp);
        % Resultados
        fig = uifigure('Name', 'Resultados del entrenamiento', ...
            'Position', [100, 100, 1100, 675], ...
            'Resize', 'off', ...
            'WindowStyle', 'modal', ...
            'Color', [0.13 0.13 0.13],...
            'Visible', 'off');  
        movegui(fig, 'center');
        % Texto 
        msg = sprintf(['Modelo entrenado correctamente\n\n' ...
              'Precisión (R²): %.2f ± %.2f\n\n' ...
              'Datos usados: %d vueltas en %d circuitos\n\n' ...
              'Error Absoluto Medio (MAE): %.4f'], ...
               res.r2, res.r2_std, res.numVueltas, res.nCircuitos, res.mae);
        uilabel(fig, 'Position', [100, 500, 900, 180], ...
                'Text', msg, ...
                'FontColor', [0.95 0.95 0.95], ...
                'FontSize', 20, ... 
                'FontWeight', 'normal', ... 
                'HorizontalAlignment', 'center', 'VerticalAlignment', 'top');
        % Gráfica
        ax = uiaxes(fig, 'Position', [85, 100, 900, 380]);
        ax.Color = [0.18 0.18 0.18];
        ax.XColor = 'w'; ax.YColor = 'w';
        ax.GridColor = [0.3 0.3 0.3];
        ax.Toolbar.Visible = 'off'; 
        grid(ax, 'on'); hold(ax, 'on');
        title(ax, 'Validación: Predicción vs Realidad', 'Color', 'w', 'FontSize', 14);
        xlabel(ax, 'Z-Score Real', 'Color', [0.8 0.8 0.8]);
        ylabel(ax, 'Z-Score Predicho', 'Color', [0.8 0.8 0.8]);
        minVal = min(min(res.Targets), min(res.Predictions));
        maxVal = max(max(res.Targets), max(res.Predictions));
        plot(ax, [minVal maxVal], [minVal maxVal], 'w--', 'LineWidth', 1);
        scatter(ax, res.Targets, res.Predictions, 25, [0 0.8 1], 'filled', 'MarkerFaceAlpha', 0.6);
        hold(ax, 'off');
        % Botón
        uibutton(fig, 'Position', [450, 30, 200, 40], ...
            'Text', 'Ir al Optimizador', ...
            'BackgroundColor', [0, 0.6, 0.2], ... 
            'FontColor', 'w', ...
            'FontSize', 18, ...
            'FontWeight', 'bold', ...
            'ButtonPushedFcn', @(btn,event) irAOptimizador(app, fig));
        fig.Visible = 'on';
        drawnow; 
        close(d);
        catch ME
        close(d);
        uialert(app.F1OptimizerUIFigure, ME.message, 'Error');
        end
        end
        % Cierra y cambia de pestaña
        function irAOptimizador(app, fig)
        close(fig); 
        app.TabGroup.SelectedTab = app.OptimizadorTab; 
        actualizarSelectorOptimizador(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.CircuitosProcesadosTab
            actualizarCircuitosProcesadosTab(app);
            elseif selectedTab == app.OptimizadorTab
                actualizarSelectorOptimizador(app); 
            end     
        end

        % Button pushed function: VolverButton_2
        function VolverButton_2Pushed(app, event)
         app.TabGroup.SelectedTab = app.CircuitosProcesadosTab;   
        end

        % Button pushed function: SalirButton
        function SalirButtonPushed(app, event)
        delete(app.F1OptimizerUIFigure);   
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
        exportarResultados(app);    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.F1OptimizerUIFigure = uifigure('Visible', 'off');
            colormap(app.F1OptimizerUIFigure, 'turbo');
            app.F1OptimizerUIFigure.Name = 'F1 Optimizer';
            app.GridLayout = uigridlayout(app.F1OptimizerUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SelectorCircuitoTab = uitab(app.TabGroup);
            app.Panel_info = uipanel(app.SelectorCircuitoTab);
            app.Panel_info.Visible = 'off';
            app.GridLayout13_2 = uigridlayout(app.Panel_info);
            app.nombrecircuitoLabel_2 = uilabel(app.GridLayout13_2);
            app.nombrecircuitoLabel_2.Text = 'Circuito de Albert Park';
            app.Image_2 = uiimage(app.GridLayout13_2);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Albert Park_layout_HD.png');
            app.numerosLabel_2 = uilabel(app.GridLayout13_2);
            app.numerosLabel_2.Text = {'Longitud:	5,303 km'; ''; 'Curvas: 16'; ''; 'Vuelta récord: 1:19.813 (Fórmula 1)'; ''; 'Inauguración: 07 de marzo de 1996 '; ''; 'Eventos: Fórmula 1, V8 Supercars'};
            app.Panel_4 = uipanel(app.GridLayout13_2);
            app.GridLayout14_2 = uigridlayout(app.Panel_4);
            app.TraccinLabel_2 = uilabel(app.GridLayout14_2);
            app.TraccinLabel_2.Text = 'Tracción:                            ● ● ○ ○ ○';
            app.FrenadasLabel_2 = uilabel(app.GridLayout14_2);
            app.FrenadasLabel_2.Text = 'Frenadas:                          ● ● ○ ○ ○';
            app.GLateralLabel_2 = uilabel(app.GridLayout14_2);
            app.GLateralLabel_2.Text = 'G Lateral:                          ● ● ● ○ ○';
            app.DownforceLabel_2 = uilabel(app.GridLayout14_2);
            app.DownforceLabel_2.Text = 'Downforce:                        ● ● ○ ○ ○';
            app.datoLabel_2 = uilabel(app.GridLayout13_2);
            app.datoLabel_2.Text = 'El circuito se construye cada año alrededor del lago Albert Park, utilizando carreteras públicas que se cierran para el evento.';
            app.XButton = uibutton(app.SelectorCircuitoTab, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Visible = 'off';
            app.XButton.Text = 'X';
            app.GridLayout2 = uigridlayout(app.SelectorCircuitoTab);
            app.SelectordeCircuitosLabel = uilabel(app.GridLayout2);
            app.SelectordeCircuitosLabel.Text = 'Selector de Circuitos';
            app.ProcesarButton = uibutton(app.GridLayout2, 'push');
            app.ProcesarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarButtonPushed, true);
            app.ProcesarButton.Text = ' Procesar → ';
            app.PanelSuzuka = uipanel(app.GridLayout2);
            app.GridLayout3_12 = uigridlayout(app.PanelSuzuka);
            app.nombreLabel_12 = uilabel(app.GridLayout3_12);
            app.nombreLabel_12.Text = {'Circuito de Suzuka'; 'Suzuka (Japón)'};
            app.Suzuka_12 = uiimage(app.GridLayout3_12);
            app.Suzuka_12.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'DPPI_00123037_387.jpg');
            app.Suzukaflag_12 = uiimage(app.GridLayout3_12);
            app.Suzukaflag_12.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Japan.png');
            app.SeleccionarCheckBoxSuzuka = uicheckbox(app.GridLayout3_12);
            app.SeleccionarCheckBoxSuzuka.Text = 'Seleccionar';
            app.ButtonSuzuka = uibutton(app.GridLayout3_12, 'push');
            app.ButtonSuzuka.ButtonPushedFcn = createCallbackFcn(app, @ButtonSuzukaPushed, true);
            app.ButtonSuzuka.Text = 'ℹ';
            app.PanelSpa = uipanel(app.GridLayout2);
            app.GridLayout3_11 = uigridlayout(app.PanelSpa);
            app.nombreLabel_11 = uilabel(app.GridLayout3_11);
            app.nombreLabel_11.Text = {'Circuito de Spa-Francorchamps'; 'Lieja (Bélgica)'};
            app.Spa_11 = uiimage(app.GridLayout3_11);
            app.Spa_11.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'DPPI_02524010_009-753x494.jpg');
            app.Spaflag_11 = uiimage(app.GridLayout3_11);
            app.Spaflag_11.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Belgium.png');
            app.SeleccionarCheckBoxSpa = uicheckbox(app.GridLayout3_11);
            app.SeleccionarCheckBoxSpa.Text = 'Seleccionar';
            app.ButtonSpa = uibutton(app.GridLayout3_11, 'push');
            app.ButtonSpa.ButtonPushedFcn = createCallbackFcn(app, @ButtonSpaPushed, true);
            app.ButtonSpa.Text = 'ℹ';
            app.PanelSingapur = uipanel(app.GridLayout2);
            app.GridLayout3_10 = uigridlayout(app.PanelSingapur);
            app.nombreLabel_10 = uilabel(app.GridLayout3_10);
            app.nombreLabel_10.Text = {'Marina Bay Street Circuit'; 'Singapur (Singapur)'};
            app.Singapur_10 = uiimage(app.GridLayout3_10);
            app.Singapur_10.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'circuito_singapur_2018_soy_motor.jpg');
            app.Singapurflag_10 = uiimage(app.GridLayout3_10);
            app.Singapurflag_10.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Singapore.png');
            app.SeleccionarCheckBoxSingapur = uicheckbox(app.GridLayout3_10);
            app.SeleccionarCheckBoxSingapur.Text = 'Seleccionar';
            app.ButtonSingapur = uibutton(app.GridLayout3_10, 'push');
            app.ButtonSingapur.ButtonPushedFcn = createCallbackFcn(app, @ButtonSingapurPushed, true);
            app.ButtonSingapur.Text = 'ℹ';
            app.PanelSilverstone = uipanel(app.GridLayout2);
            app.GridLayout3_9 = uigridlayout(app.PanelSilverstone);
            app.nombreLabel_9 = uilabel(app.GridLayout3_9);
            app.nombreLabel_9.Text = {'Circuito de Silverstone'; 'Silverstone (Reino Unido)'};
            app.Silverstone_9 = uiimage(app.GridLayout3_9);
            app.Silverstone_9.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'silverstone-gp-gran-bretana-2018-soymotor.jpg');
            app.Silverstoneflag_9 = uiimage(app.GridLayout3_9);
            app.Silverstoneflag_9.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_the_United_Kingdom.png');
            app.SeleccionarCheckBoxSilverstone = uicheckbox(app.GridLayout3_9);
            app.SeleccionarCheckBoxSilverstone.Text = 'Seleccionar';
            app.ButtonSilverstone = uibutton(app.GridLayout3_9, 'push');
            app.ButtonSilverstone.ButtonPushedFcn = createCallbackFcn(app, @ButtonSilverstonePushed, true);
            app.ButtonSilverstone.Text = 'ℹ';
            app.PanelMonza = uipanel(app.GridLayout2);
            app.GridLayout3_8 = uigridlayout(app.PanelMonza);
            app.nombreLabel_8 = uilabel(app.GridLayout3_8);
            app.nombreLabel_8.Text = {'Autodromo Nazionale di Monza'; 'Monza (Italia)'};
            app.Monza_8 = uiimage(app.GridLayout3_8);
            app.Monza_8.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Captura de pantalla 2026-01-27 131605.png');
            app.Monzaflag_8 = uiimage(app.GridLayout3_8);
            app.Monzaflag_8.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Italy.png');
            app.SeleccionarCheckBoxMonza = uicheckbox(app.GridLayout3_8);
            app.SeleccionarCheckBoxMonza.Text = 'Seleccionar';
            app.ButtonMonza = uibutton(app.GridLayout3_8, 'push');
            app.ButtonMonza.ButtonPushedFcn = createCallbackFcn(app, @ButtonMonzaPushed, true);
            app.ButtonMonza.Text = 'ℹ';
            app.PanelMonaco = uipanel(app.GridLayout2);
            app.GridLayout3_7 = uigridlayout(app.PanelMonaco);
            app.nombreLabel_7 = uilabel(app.GridLayout3_7);
            app.nombreLabel_7.Text = {'Circuito de Mónaco'; 'Mónaco (Mónaco)'};
            app.Monaco_7 = uiimage(app.GridLayout3_7);
            app.Monaco_7.ImageSource = fullfile(pathToMLAPP, 'imagenes', '0000004126_HiRes_06KPQ0X0QTFUKG08Q0IRL0CSVNUP.jpg');
            app.Monacoflag_7 = uiimage(app.GridLayout3_7);
            app.Monacoflag_7.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Monaco.png');
            app.SeleccionarCheckBoxMonaco = uicheckbox(app.GridLayout3_7);
            app.SeleccionarCheckBoxMonaco.Text = 'Seleccionar';
            app.ButtonMonaco = uibutton(app.GridLayout3_7, 'push');
            app.ButtonMonaco.ButtonPushedFcn = createCallbackFcn(app, @ButtonMonacoPushed, true);
            app.ButtonMonaco.Text = 'ℹ';
            app.PanelInterlagos = uipanel(app.GridLayout2);
            app.GridLayout3_6 = uigridlayout(app.PanelInterlagos);
            app.nombreLabel_6 = uilabel(app.GridLayout3_6);
            app.nombreLabel_6.Text = {'Autódromo José Carlos Pace'; '	São Paulo (Brasil)'};
            app.Interlagos_6 = uiimage(app.GridLayout3_6);
            app.Interlagos_6.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Sao-Paulo-Grand-Prix-time-0d36154.jpg');
            app.Interlagosflag_6 = uiimage(app.GridLayout3_6);
            app.Interlagosflag_6.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Brazil.png');
            app.SeleccionarCheckBoxInterlagos = uicheckbox(app.GridLayout3_6);
            app.SeleccionarCheckBoxInterlagos.Text = 'Seleccionar';
            app.ButtonInterlagos = uibutton(app.GridLayout3_6, 'push');
            app.ButtonInterlagos.ButtonPushedFcn = createCallbackFcn(app, @ButtonInterlagosPushed, true);
            app.ButtonInterlagos.Text = 'ℹ';
            app.PanelCanada = uipanel(app.GridLayout2);
            app.GridLayout3_2 = uigridlayout(app.PanelCanada);
            app.nombreLabel_2 = uilabel(app.GridLayout3_2);
            app.nombreLabel_2.Text = {'Circuito Gilles Villeneuve'; 'Montreal (Canadá)'};
            app.Canada_2 = uiimage(app.GridLayout3_2);
            app.Canada_2.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Captura de pantalla 2026-01-27 121837.png');
            app.Canadaflag_2 = uiimage(app.GridLayout3_2);
            app.Canadaflag_2.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Canada.png');
            app.SeleccionarCheckBoxCanada = uicheckbox(app.GridLayout3_2);
            app.SeleccionarCheckBoxCanada.Text = 'Seleccionar';
            app.ButtonCanada = uibutton(app.GridLayout3_2, 'push');
            app.ButtonCanada.ButtonPushedFcn = createCallbackFcn(app, @ButtonCanadaPushed, true);
            app.ButtonCanada.Text = 'ℹ';
            app.PanelBarein = uipanel(app.GridLayout2);
            app.GridLayout3_5 = uigridlayout(app.PanelBarein);
            app.nombreLabel_5 = uilabel(app.GridLayout3_5);
            app.nombreLabel_5.Text = {'Circuito Internacional de Baréin'; 'Sakhir (Baréin)'};
            app.Barein_5 = uiimage(app.GridLayout3_5);
            app.Barein_5.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'bahrein-gp.jpeg');
            app.Bareinflag_5 = uiimage(app.GridLayout3_5);
            app.Bareinflag_5.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Bahrain.png');
            app.SeleccionarCheckBoxBarein = uicheckbox(app.GridLayout3_5);
            app.SeleccionarCheckBoxBarein.Text = 'Seleccionar';
            app.ButtonBarein = uibutton(app.GridLayout3_5, 'push');
            app.ButtonBarein.ButtonPushedFcn = createCallbackFcn(app, @ButtonBareinPushed, true);
            app.ButtonBarein.Text = 'ℹ';
            app.PanelBarcelona = uipanel(app.GridLayout2);
            app.GridLayout3_4 = uigridlayout(app.PanelBarcelona);
            app.nombreLabel_4 = uilabel(app.GridLayout3_4);
            app.nombreLabel_4.Text = {'Circuit de Barcelona-Catalunya'; 'Montmeló (España)'};
            app.barcelona_4 = uiimage(app.GridLayout3_4);
            app.barcelona_4.ImageSource = fullfile(pathToMLAPP, 'imagenes', '65b0e02d5c9e4.r_d.385-211-15584.jpeg');
            app.barcelonaflag_4 = uiimage(app.GridLayout3_4);
            app.barcelonaflag_4.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Spain.png');
            app.SeleccionarCheckBoxBarcelona = uicheckbox(app.GridLayout3_4);
            app.SeleccionarCheckBoxBarcelona.Text = 'Seleccionar';
            app.ButtonBarcelona = uibutton(app.GridLayout3_4, 'push');
            app.ButtonBarcelona.ButtonPushedFcn = createCallbackFcn(app, @ButtonBarcelonaPushed, true);
            app.ButtonBarcelona.Text = 'ℹ';
            app.PanelAustria = uipanel(app.GridLayout2);
            app.GridLayout3_3 = uigridlayout(app.PanelAustria);
            app.nombreLabel_3 = uilabel(app.GridLayout3_3);
            app.nombreLabel_3.Text = {'Red Bull Ring'; 'Spielberg (Austria)'};
            app.austria_3 = uiimage(app.GridLayout3_3);
            app.austria_3.ImageSource = fullfile(pathToMLAPP, 'imagenes', '00_circuito_red_bull_ring_motogp1.jpg');
            app.austriaflag_3 = uiimage(app.GridLayout3_3);
            app.austriaflag_3.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Austria.png');
            app.ButtonAustria = uibutton(app.GridLayout3_3, 'push');
            app.ButtonAustria.ButtonPushedFcn = createCallbackFcn(app, @ButtonAustriaPushed, true);
            app.ButtonAustria.Text = 'ℹ';
            app.SeleccionarCheckBoxAustria = uicheckbox(app.GridLayout3_3);
            app.SeleccionarCheckBoxAustria.Text = 'Seleccionar';
            app.PanelAustralia = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.PanelAustralia);
            app.nombreLabel = uilabel(app.GridLayout3);
            app.nombreLabel.Text = {'Circuito de Albert Park'; 'Melbourne (Australia)'};
            app.australia = uiimage(app.GridLayout3);
            app.australia.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'australia-circuito-f1-soymotor.jpg');
            app.australiaflag = uiimage(app.GridLayout3);
            app.australiaflag.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Australia.png');
            app.ButtonAustralia = uibutton(app.GridLayout3, 'push');
            app.ButtonAustralia.ButtonPushedFcn = createCallbackFcn(app, @ButtonAustraliaPushed, true);
            app.ButtonAustralia.Text = 'ℹ';
            app.SeleccionarCheckBoxAustralia = uicheckbox(app.GridLayout3);
            app.SeleccionarCheckBoxAustralia.Text = 'Seleccionar';
            app.SeleccionarTodosCheckBox = uicheckbox(app.GridLayout2);
            app.SeleccionarTodosCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeleccionarTodosCheckBoxValueChanged, true);
            app.SeleccionarTodosCheckBox.Text = ' Seleccionar Todos';
            app.TemporadaSwitch = uiswitch(app.GridLayout2, 'slider');
            app.TemporadaSwitch.Items = {'2025', '2026'};
            app.TemporadaSwitch.ValueChangedFcn = createCallbackFcn(app, @TemporadaSwitchValueChanged, true);
            app.TemporadaSwitch.Value = '2025';
            app.CircuitosProcesadosTab = uitab(app.TabGroup);
            app.Panel_info_2 = uipanel(app.CircuitosProcesadosTab);
            app.Panel_info_2.Visible = 'off';
            app.GridLayout13_3 = uigridlayout(app.Panel_info_2);
            app.nombrecircuitoLabel_3 = uilabel(app.GridLayout13_3);
            app.nombrecircuitoLabel_3.Text = '';
            app.TextArea = uitextarea(app.GridLayout13_3);
            app.TelemetriaLabel = uilabel(app.GridLayout13_3);
            app.TelemetriaLabel.Text = '';
            app.Panel_5 = uipanel(app.GridLayout13_3);
            app.GridLayout25 = uigridlayout(app.Panel_5);
            app.UIAxes = uiaxes(app.GridLayout25);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout25);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel_6 = uipanel(app.GridLayout13_3);
            app.GridLayout26 = uigridlayout(app.Panel_6);
            app.MetricasLabel = uilabel(app.GridLayout26);
            app.MetricasLabel.Text = '';
            app.MejorVueltaLabel = uilabel(app.GridLayout26);
            app.MejorVueltaLabel.Text = '';
            app.XButton_2 = uibutton(app.CircuitosProcesadosTab, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Visible = 'off';
            app.XButton_2.Text = 'X';
            app.GridLayout2_CircuitosProcesados = uigridlayout(app.CircuitosProcesadosTab);
            app.CircuitosProcesadosLabel = uilabel(app.GridLayout2_CircuitosProcesados);
            app.CircuitosProcesadosLabel.Text = 'Circuitos Procesados';
            app.EntrenarModeloButton = uibutton(app.GridLayout2_CircuitosProcesados, 'push');
            app.EntrenarModeloButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarModeloButtonPushed, true);
            app.EntrenarModeloButton.Text = '  Entrenar Modelo → ';
            app.PanelSuzuka_2 = uipanel(app.GridLayout2_CircuitosProcesados);
            app.GridLayout3_13 = uigridlayout(app.PanelSuzuka_2);
            app.vueltasLabel_13 = uilabel(app.GridLayout3_13);
            app.vueltasLabel_13.Text = '';
            app.Suzuka_13 = uiimage(app.GridLayout3_13);
            app.Suzuka_13.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'DPPI_00123037_387.jpg');
            app.Suzukaflag_13 = uiimage(app.GridLayout3_13);
            app.Suzukaflag_13.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Japan.png');
            app.ButtonSuzuka_2 = uibutton(app.GridLayout3_13, 'push');
            app.ButtonSuzuka_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonSuzuka_2Pushed, true);
            app.ButtonSuzuka_2.Text = 'ℹ';
            app.PanelSpa_2 = uipanel(app.GridLayout2_CircuitosProcesados);
            app.GridLayout3_14 = uigridlayout(app.PanelSpa_2);
            app.vueltasLabel_14 = uilabel(app.GridLayout3_14);
            app.vueltasLabel_14.Text = '';
            app.Spa_12 = uiimage(app.GridLayout3_14);
            app.Spa_12.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'DPPI_02524010_009-753x494.jpg');
            app.Spaflag_12 = uiimage(app.GridLayout3_14);
            app.Spaflag_12.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Belgium.png');
            app.ButtonSpa_2 = uibutton(app.GridLayout3_14, 'push');
            app.ButtonSpa_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonSpa_2Pushed, true);
            app.ButtonSpa_2.Text = 'ℹ';
            app.PanelSingapur_2 = uipanel(app.GridLayout2_CircuitosProcesados);
            app.GridLayout3_15 = uigridlayout(app.PanelSingapur_2);
            app.vueltasLabel_15 = uilabel(app.GridLayout3_15);
            app.vueltasLabel_15.Text = '';
            app.Singapur_11 = uiimage(app.GridLayout3_15);
            app.Singapur_11.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'circuito_singapur_2018_soy_motor.jpg');
            app.Singapurflag_11 = uiimage(app.GridLayout3_15);
            app.Singapurflag_11.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Singapore.png');
            app.ButtonSingapur_2 = uibutton(app.GridLayout3_15, 'push');
            app.ButtonSingapur_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonSingapur_2Pushed, true);
            app.ButtonSingapur_2.Text = 'ℹ';
            app.PanelSilverstone_2 = uipanel(app.GridLayout2_CircuitosProcesados);
            app.GridLayout3_16 = uigridlayout(app.PanelSilverstone_2);
            app.vueltasLabel_16 = uilabel(app.GridLayout3_16);
            app.vueltasLabel_16.Text = '';
            app.Silverstone_10 = uiimage(app.GridLayout3_16);
            app.Silverstone_10.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'silverstone-gp-gran-bretana-2018-soymotor.jpg');
            app.Silverstoneflag_10 = uiimage(app.GridLayout3_16);
            app.Silverstoneflag_10.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_the_United_Kingdom.png');
            app.ButtonSilverstone_2 = uibutton(app.GridLayout3_16, 'push');
            app.ButtonSilverstone_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonSilverstone_2Pushed, true);
            app.ButtonSilverstone_2.Text = 'ℹ';
            app.PanelMonza_2 = uipanel(app.GridLayout2_CircuitosProcesados);
            app.GridLayout3_17 = uigridlayout(app.PanelMonza_2);
            app.vueltasLabel_17 = uilabel(app.GridLayout3_17);
            app.vueltasLabel_17.Text = '';
            app.Monza_9 = uiimage(app.GridLayout3_17);
            app.Monza_9.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Captura de pantalla 2026-01-27 131605.png');
            app.Monzaflag_9 = uiimage(app.GridLayout3_17);
            app.Monzaflag_9.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Italy.png');
            app.ButtonMonza_2 = uibutton(app.GridLayout3_17, 'push');
            app.ButtonMonza_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonMonza_2Pushed, true);
            app.ButtonMonza_2.Text = 'ℹ';
            app.PanelMonaco_2 = uipanel(app.GridLayout2_CircuitosProcesados);
            app.GridLayout3_18 = uigridlayout(app.PanelMonaco_2);
            app.vueltasLabel_18 = uilabel(app.GridLayout3_18);
            app.vueltasLabel_18.Text = '';
            app.Monaco_8 = uiimage(app.GridLayout3_18);
            app.Monaco_8.ImageSource = fullfile(pathToMLAPP, 'imagenes', '0000004126_HiRes_06KPQ0X0QTFUKG08Q0IRL0CSVNUP.jpg');
            app.Monacoflag_8 = uiimage(app.GridLayout3_18);
            app.Monacoflag_8.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Monaco.png');
            app.ButtonMonaco_2 = uibutton(app.GridLayout3_18, 'push');
            app.ButtonMonaco_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonMonaco_2Pushed, true);
            app.ButtonMonaco_2.Text = 'ℹ';
            app.PanelInterlagos_2 = uipanel(app.GridLayout2_CircuitosProcesados);
            app.GridLayout3_19 = uigridlayout(app.PanelInterlagos_2);
            app.vueltasLabel_19 = uilabel(app.GridLayout3_19);
            app.vueltasLabel_19.Text = '';
            app.Interlagos_7 = uiimage(app.GridLayout3_19);
            app.Interlagos_7.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Sao-Paulo-Grand-Prix-time-0d36154.jpg');
            app.Interlagosflag_7 = uiimage(app.GridLayout3_19);
            app.Interlagosflag_7.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Brazil.png');
            app.ButtonInterlagos_2 = uibutton(app.GridLayout3_19, 'push');
            app.ButtonInterlagos_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonInterlagos_2Pushed, true);
            app.ButtonInterlagos_2.Text = 'ℹ';
            app.PanelCanada_2 = uipanel(app.GridLayout2_CircuitosProcesados);
            app.GridLayout3_20 = uigridlayout(app.PanelCanada_2);
            app.vueltasLabel_20 = uilabel(app.GridLayout3_20);
            app.vueltasLabel_20.Text = '';
            app.Canada_3 = uiimage(app.GridLayout3_20);
            app.Canada_3.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Captura de pantalla 2026-01-27 121837.png');
            app.Canadaflag_3 = uiimage(app.GridLayout3_20);
            app.Canadaflag_3.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Canada.png');
            app.ButtonCanada_2 = uibutton(app.GridLayout3_20, 'push');
            app.ButtonCanada_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonCanada_2Pushed, true);
            app.ButtonCanada_2.Text = 'ℹ';
            app.PanelBarein_2 = uipanel(app.GridLayout2_CircuitosProcesados);
            app.GridLayout3_21 = uigridlayout(app.PanelBarein_2);
            app.vueltasLabel_21 = uilabel(app.GridLayout3_21);
            app.vueltasLabel_21.Text = '';
            app.Barein_6 = uiimage(app.GridLayout3_21);
            app.Barein_6.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'bahrein-gp.jpeg');
            app.Bareinflag_6 = uiimage(app.GridLayout3_21);
            app.Bareinflag_6.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Bahrain.png');
            app.ButtonBarein_2 = uibutton(app.GridLayout3_21, 'push');
            app.ButtonBarein_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonBarein_2Pushed, true);
            app.ButtonBarein_2.Text = 'ℹ';
            app.PanelBarcelona_2 = uipanel(app.GridLayout2_CircuitosProcesados);
            app.GridLayout3_22 = uigridlayout(app.PanelBarcelona_2);
            app.vueltasLabel_22 = uilabel(app.GridLayout3_22);
            app.vueltasLabel_22.Text = '';
            app.barcelona_5 = uiimage(app.GridLayout3_22);
            app.barcelona_5.ImageSource = fullfile(pathToMLAPP, 'imagenes', '65b0e02d5c9e4.r_d.385-211-15584.jpeg');
            app.barcelonaflag_5 = uiimage(app.GridLayout3_22);
            app.barcelonaflag_5.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Spain.png');
            app.ButtonBarcelona_2 = uibutton(app.GridLayout3_22, 'push');
            app.ButtonBarcelona_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonBarcelona_2Pushed, true);
            app.ButtonBarcelona_2.Text = 'ℹ';
            app.PanelAustria_2 = uipanel(app.GridLayout2_CircuitosProcesados);
            app.GridLayout3_23 = uigridlayout(app.PanelAustria_2);
            app.vueltasLabel_23 = uilabel(app.GridLayout3_23);
            app.vueltasLabel_23.Text = '';
            app.austria_4 = uiimage(app.GridLayout3_23);
            app.austria_4.ImageSource = fullfile(pathToMLAPP, 'imagenes', '00_circuito_red_bull_ring_motogp1.jpg');
            app.austriaflag_4 = uiimage(app.GridLayout3_23);
            app.austriaflag_4.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Austria.png');
            app.ButtonAustria_2 = uibutton(app.GridLayout3_23, 'push');
            app.ButtonAustria_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonAustria_2Pushed, true);
            app.ButtonAustria_2.Text = 'ℹ';
            app.PanelAustralia_2 = uipanel(app.GridLayout2_CircuitosProcesados);
            app.GridLayout3_24 = uigridlayout(app.PanelAustralia_2);
            app.vueltasLabel_24 = uilabel(app.GridLayout3_24);
            app.vueltasLabel_24.Text = '';
            app.australia_2 = uiimage(app.GridLayout3_24);
            app.australia_2.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'australia-circuito-f1-soymotor.jpg');
            app.australiaflag_2 = uiimage(app.GridLayout3_24);
            app.australiaflag_2.ImageSource = fullfile(pathToMLAPP, 'imagenes', 'Flag_of_Australia.png');
            app.ButtonAustralia_2 = uibutton(app.GridLayout3_24, 'push');
            app.ButtonAustralia_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonAustralia_2Pushed, true);
            app.ButtonAustralia_2.Text = 'ℹ';
            app.VolverButton = uibutton(app.GridLayout2_CircuitosProcesados, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = '← Volver      ';
            app.OptimizadorTab = uitab(app.TabGroup);
            app.GridLayoutOptimizador = uigridlayout(app.OptimizadorTab);
            app.VolverButton_2 = uibutton(app.GridLayoutOptimizador, 'push');
            app.VolverButton_2.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_2Pushed, true);
            app.VolverButton_2.Text = '← Volver      ';
            app.SalirButton = uibutton(app.GridLayoutOptimizador, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButtonPushed, true);
            app.SalirButton.Text = 'Salir ';
            app.OptimizadorLabel = uilabel(app.GridLayoutOptimizador);
            app.OptimizadorLabel.Text = 'Optimizador';
            app.PanelOptimizador = uipanel(app.GridLayoutOptimizador);
            app.PanelOptimizador.Title = 'Clic en la bandera para optimizar setup';
            app.GridSelectorOptimizador = uigridlayout(app.PanelOptimizador);
            app.PanelCentral = uipanel(app.GridLayoutOptimizador);
            app.PanelDerecho = uipanel(app.GridLayoutOptimizador);
            app.GridLayoutDerecho = uigridlayout(app.PanelDerecho);
            app.ExportarButton = uibutton(app.GridLayoutOptimizador, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Text = 'Exportar Datos';
            % Show the figure after all components are created
            app.F1OptimizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = F1Optimizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.F1OptimizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.F1OptimizerUIFigure)
        end
    end
end